classdef TT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Reproducir                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Lamp                            matlab.ui.control.Lamp
        Guardar                         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AdquisicinTab                   matlab.ui.container.Tab
        FrecuenciademuestreoDropDownLabel  matlab.ui.control.Label
        fMuestreo                       matlab.ui.control.DropDown
        NombreEditFieldLabel            matlab.ui.control.Label
        Nombre                          matlab.ui.control.EditField
        EdadEditFieldLabel              matlab.ui.control.Label
        Edad                            matlab.ui.control.NumericEditField
        DuracinsegLabel                 matlab.ui.control.Label
        Duracion                        matlab.ui.control.NumericEditField
        ResolucinDropDownLabel          matlab.ui.control.Label
        Resolucion                      matlab.ui.control.DropDown
        DirSelect                       matlab.ui.control.Button
        Ready2Rec                       matlab.ui.control.CheckBox
        SexoDropDownLabel               matlab.ui.control.Label
        Sexo                            matlab.ui.control.DropDown
        VoluntarioLabel                 matlab.ui.control.Label
        EscolaridadDropDownLabel        matlab.ui.control.Label
        Escolaridad                     matlab.ui.control.DropDown
        AudioLabel                      matlab.ui.control.Label
        PatronesTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        AjustesTab                      matlab.ui.container.TabGroup
        PreprocesamientoTab             matlab.ui.container.Tab
        FiltroTitle                     matlab.ui.control.Label
        FCorteL                         matlab.ui.control.NumericEditField
        FCorteH                         matlab.ui.control.NumericEditField
        Bode                            matlab.ui.control.StateButton
        AplicarPre                      matlab.ui.control.Button
        TipodeventanaDropDownLabel      matlab.ui.control.Label
        TipoVentana                     matlab.ui.control.DropDown
        BetaKaiser                      matlab.ui.control.NumericEditField
        BetaKaiserLabel                 matlab.ui.control.Label
        TamaodelaventanamsLabel         matlab.ui.control.Label
        TWin                            matlab.ui.control.NumericEditField
        VVentana                        matlab.ui.control.StateButton
        CorteInfLabel                   matlab.ui.control.Label
        CorteSupLabel                   matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        EspectroTab                     matlab.ui.container.Tab
        desobreposicinLabel             matlab.ui.control.Label
        OverKnob                        matlab.ui.control.Knob
        Demuestrear                     matlab.ui.control.CheckBox
        GuardarEspButton                matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        NFFTDropDownLabel               matlab.ui.control.Label
        NFFTDropDown                    matlab.ui.control.DropDown
        EspectrogramaLabel              matlab.ui.control.Label
        BancoTab                        matlab.ui.container.Tab
        NmerofiltrosSpinnerLabel        matlab.ui.control.Label
        NMelFilter                      matlab.ui.control.Spinner
        LimiteinferiorEditFieldLabel    matlab.ui.control.Label
        LimiteInferior                  matlab.ui.control.NumericEditField
        LimitesuperiorEditFieldLabel    matlab.ui.control.Label
        LimiteSuperior                  matlab.ui.control.NumericEditField
        MelBandwidth                    matlab.ui.control.Spinner
        ParametroFB                     matlab.ui.control.Switch
        GenFBButton                     matlab.ui.control.Button
        BancodeFiltrosLabel             matlab.ui.control.Label
        NormalizarButton                matlab.ui.control.Button
        EscalaDropDownLabel             matlab.ui.control.Label
        EscalaDropDown                  matlab.ui.control.DropDown
        EspectroenescalaButton          matlab.ui.control.StateButton
        Label_3                         matlab.ui.control.Label
        MFCCTab                         matlab.ui.container.Tab
        CoeficientesMFCCEditFieldLabel  matlab.ui.control.Label
        nMFCC                           matlab.ui.control.NumericEditField
        IncluirLogE                     matlab.ui.control.CheckBox
        IncluirDeltas2do                matlab.ui.control.CheckBox
        IncluirDeltas                   matlab.ui.control.CheckBox
        ProcesarButton                  matlab.ui.control.Button
        MostrarDeltaButton              matlab.ui.control.StateButton
        MostrarDeltaDeltaButton         matlab.ui.control.StateButton
        MostrarEspectroButton           matlab.ui.control.StateButton
        CoeficientesCepstralesenEscalaMelLabel  matlab.ui.control.Label
        ReconocedorTab                  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        ActivarSwitchLabel              matlab.ui.control.Label
        ActivarSwitch                   matlab.ui.control.RockerSwitch
        Lamp2                           matlab.ui.control.Lamp
        RedLabel                        matlab.ui.control.Label
        SelecTest                       matlab.ui.control.DropDown
        RedNeuronalSwitchLabel          matlab.ui.control.Label
        RedNeuronalSwitch               matlab.ui.control.Switch
        VoluntarioLabel_2               matlab.ui.control.Label
        NombreEditFieldLabel_2          matlab.ui.control.Label
        Nombre_2                        matlab.ui.control.EditField
        EdadEditFieldLabel_2            matlab.ui.control.Label
        Edad_2                          matlab.ui.control.NumericEditField
        SexoDropDownLabel_2             matlab.ui.control.Label
        Sexo_2                          matlab.ui.control.DropDown
        EscolaridadDropDownLabel_2      matlab.ui.control.Label
        Escolaridad_2                   matlab.ui.control.DropDown
        GuardarResultados               matlab.ui.control.Button
        VoluntarioLabel_3               matlab.ui.control.Label
        MostrarButton                   matlab.ui.control.StateButton
        PruebaLabel                     matlab.ui.control.Label
        SelecPrueba                     matlab.ui.control.DropDown
        Mensaje                         matlab.ui.control.Label
        Status                          matlab.ui.control.Label
        Palabra                         matlab.ui.control.Label
        ApagarSwitchLabel               matlab.ui.control.Label
        Apagar                          matlab.ui.control.ToggleSwitch
        GrabarLabel                     matlab.ui.control.Label
        Grabar                          matlab.ui.control.Switch
    end

%{ 
  El programa comienza con todos los campos desactivados salvo los de información del
  voluntario y la configuración de la grabación: depues de que la función startup
  inicializa algunas variables y una vez que el usuario introduce la información requerida
  este selecciona la checkbox ready2Rec, la cual habilita los controles de grabación
  para luego llamar a la función Iniciar(app) la cual hace las comprobaciones necesarias para el
  control de los archivos de audio.
  Las funciones Grabar, Reproducir y Guardar controlan la adquisición de la señal de audio,
  Guardar genera el archivo .wav y agrega el registro de esta grabación al archivo de control del
  voluntario; Apagar actualiza el archivo main.csv donde se encuentra el registro de los voluntarios
  grabados.
  Las funciones de la tab Patrones son para la configuración de la extracción de características y
  trabajan con la última palabra guardada en la memoria, su función es obtener los parámetros con
  los que se extraerán los patrones al resto de la base de datos
  %}
    
    
    properties (Access = private)
        % FALTA DEFINIR COMO SE VA A SOLICITAR LA INCLUSIÓN DE LA ENERGÍA LOG
        
        % Variables de Adquisición
        audio       % Objeto clase audiorecorder usado para la adquisición de audio
        signal      % Vector numérico conteniendo la señal de audio adquirida
        dirname     % Directorio general de la base de datos
        main        % Tabla con el registro general de los voluntarios que se han grabado
        volun       % Tabla con el registro de palabras grabadas y procesadas por el voluntario de turno
        I           % Índice para localizar a la ultima palabra grabada por una persona
        VCeros      % Vector de ceros de la longitud de la lista de palabras "Diccionario"
        VLogical    % Vector de ceros lógico con longitud de la lista de palabras
        mainfile    % Ruta al .csv principal de la base de datos
        volfile     % Ruta al .csv del voluntario
        voldir      % Directorio del voluntario
      
        % Variables de Preprocesamiento
        RecordedFs  % Frecuencia de muestreo de un audio previamente grabado
        Cutoff      % Frecuencias de cortes
        filtrada    % Señal filtrada
        wavfile     % Ruta del ultimo archivo de audio grabado
        t           % Vector con el tiempo de la señal
        Dsample     % Señal demuestrada
        Dfs         % Frecuencia de muestreo de la señal demuestreada
        P           % Potenciograma
        Fp          % Frecuencia espectro
        
        % Variables de Segmentación (Framing)
        WinLength   % Ancho de la ventana en muestras
        Overlap     % Sobreposición en muestras
        HopLength   % Número de muestras de avance
        numHops     % Número de frames
        coloffsets  % Vector de avance por cada segmento dado en índices
        
        % Variables de espectrograma
        Tespectro   % Vector de tiempo para espectro

        fespectro   % Vector de frecuencias para graficar espectrograma
        Pxx         % Densidad espectral de potencia
        
        % Variables para extracción de características
        Framedfs    % Frecuencia de muestreo después de incluir o no demuestrado
        Range       % Rango de frecuencias del banco de filtros mel
        FBBandwidth % Ancho de banda de cada filtro del banco de filtros mel
        NFB         % Número de filtros en el banco de filtros mel
        Mejes       %  del banco de filtros en Mels
        Fejes       %  del banco de filtros en Hertz
        vcoeffs     % Vector del 1 al número de coeficientes calculados
        FilterBank  % Matriz con el banco de filtros mel
        logE        % Logaritmo de la energía de la señal
        DCTmatrix   % Matriz para aplicar la Transformada Discreta de Coseno a la señal
        PlotFBfreq  % Vector de frecuencia en Hz para graficar el Banco de Filtros Mel
        
        % Reconocedor de voz
        livespeech % Description
        livespec
        live    
        numBands
        epsil
        audioIn
        waveBuffer
        Timer
        CNN
        Net1
        Net2
        Net3
        countThreshold
        YBuffer
        probBuffer
        words1
        words2
        words3
        
        % Objetos generados
        Win         % Ventana
        pasabanda   % Objeto tipo digitalFilter con la información del filtro pasabandas
        Framed      % Señal ventaneada y segmentada
        Sxx         % Parte real de la FFT de la señal segmentada 
        MelSpect    % FFT de la señal en escala mel
        coeffs      % Coeficientes cepstrales en escala mel
        Delta       % Cambio entre los MFCC
        Delta2      % Cambro entre Deltas
        
        varname
        volname
        mainfilename
        Diccionario
        prueba
        Vtest

    end
    
    properties (Access = public, SetObservable=true)
        
        weights     % Pesos de la red
        nFilterBank % Banco de filtros normalizado
        pWindow     % Matriz de ventanas para reducir tiempo de cómputo
        nfft        % Longitud de la DFT
        filtro      % Polinomio del filtro pasa banda
        Idx         % Matriz de índices para segmentar la señal
        processdir  % Processing file dir

    end
    % Anotar los valores por default de los campos de entrada de datos de la GUI
    methods (Access = private)  
  
        function Iniciar(app)
            app.mainfile = fullfile(app.dirname, app.mainfilename);
            app.voldir = fullfile(app.dirname, app.Nombre.Value);
            app.volfile = fullfile(app.voldir, strcat(app.Nombre.Value, '.csv'));
            if exist(app.mainfile, 'file') 
            % Existe main.csv, no es la primera grabacion
                if isempty(app.main) % Primera vez que se carga el archivo main.csv
                    app.main = readtable(app.mainfile, 'ReadRowNames', true);
                end
                if ismember(app.Nombre.Value, app.main.Properties.RowNames)
                % El voluntario ha sido registrado previamente
                    if exist(app.volfile, 'file')   
                        app.volun = readtable(app.volfile);
                        if height(app.volun) ~= length(app.Diccionario)
                            AumentarListaPalabras(app);
                        end
                    else
                        text = sprintf('%s\n%s','Algo anda mal: el voluntario está registrado pero no',...
                                       'existe su archivo');
                        uialert(app.UIFigure, text, 'Error');
                    end
                else
                % Es un voluntario nuevo, no está en main.csv
                    ID = height(app.main)+1;
                    temp = table(ID, app.Edad.Value, {app.Sexo.Value},... 
                                 {app.Escolaridad.Value}, 'VariableNames',...
                                 app.varname, 'RowNames', {app.Nombre.Value});
                    app.volun = table(app.Diccionario, app.VCeros,...
                                      app.VLogical, 'VariableNames', app.volname);
                    app.main = [app.main; temp];
                end
            else
            % No existe main.csv, es la primera grabación en esa base de datos
                app.main = table(1, app.Edad.Value, {app.Sexo.Value},... 
                                 {app.Escolaridad.Value}, 'VariableNames',...
                                 app.varname, 'RowNames', {app.Nombre.Value});
                mkdir(app.voldir);
                app.volun = table(app.Diccionario, app.VCeros,...
                                  app.VLogical, 'VariableNames', app.volname);
            end
        end
    
        function Filter(app, dir)
            cla(app.UIAxes, 'reset'); cla(app.UIAxes2, 'reset');
            [x,Fs] = audioread(dir);
            app.RecordedFs = Fs;
            app.Cutoff(1) = app.FCorteL.Value;
            app.Cutoff(2) = app.FCorteH.Value;            
            [app.filtrada, app.filtro] = bandpass(x, app.Cutoff, app.RecordedFs);
            app.t = (0:length(x)-1)/app.RecordedFs;
            plot(app.UIAxes, app.t, [x, app.filtrada]);
            xlabel(app.UIAxes, 'Tiempo (s)');  
            legend(app.UIAxes, 'Original', 'Filtradad')
            title(app.UIAxes, 'Señal Filtrada vs Señal Original')
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            [app.P, app.Fp] = pspectrum([x, app.filtrada], app.RecordedFs,...
                                        'Leakage', 0.9);    
            plot(app.UIAxes2, app.Fp, 10*log10(app.P))
            axis(app.UIAxes2, 'tight')
            app.UIAxes2.YTickLabel = app.UIAxes2.YTick/1000;
            xlabel(app.UIAxes2, 'Frecuencia (Hz)');
            ylabel(app.UIAxes2, 'Potencia espectral (dB)');
            oracion = "Filtro pasabadas con cortes a: %d y %d Hz";
            title(app.UIAxes2, sprintf(oracion, app.Cutoff(1), app.Cutoff(2)))    
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
        end
    
        function filterBankParameters (app)
            profile on
            cla(app.UIAxes, 'reset');
            x = 0:50:app.Framedfs/2; 
            app.Range = [app.LimiteInferior.Value app.LimiteSuperior.Value];
            [y, rango] = Hz2Escala(app, x);
            line(app.UIAxes, x, y);
            % legend(app.UIAxes, 'Escala Mel', 'location', 'southeast')
            %app.UIAxes.XTickLabel = app.UIAxes.XTick/1000;
            %app.UIAxes.YTickLabel = app.UIAxes.YTick/1000;
            hold(app.UIAxes);
            axis(app.UIAxes, [0 app.Range(2) 0 y(end)]) 
            title(app.UIAxes, 'Ejes de cada filtro con ancho de banda constante en Mel y 50% sobreposición');
            xlabel(app.UIAxes, 'Frecuencia (kHz)');
            ylabel(app.UIAxes, 'Frecuencia Mel (kMels)');    
            if strcmpi(app.ParametroFB.Value, 'Bandwidth')
                app.FBBandwidth = app.MelBandwidth.Value;
                avance = app.FBBandwidth/2;
                app.Mejes = rango(1): avance: rango(2);
                N = length(app.Mejes);
                app.NFB = N-2;
            else
                app.NFB = app.NMelFilter.Value;
                N = app.NFB + 2;
                app.Mejes = linspace(rango(1), rango(2), N);
                app.FBBandwidth = app.Mejes(3) - app.Mejes(1);
            end
            Escala2Hz(app) 
            for i = 1:N
                line(app.UIAxes, [0 app.Fejes(i)], [app.Mejes(i) app.Mejes(i)],...
                     'LineStyle', '-.', 'Color', 'r', 'HandleVisibility','off');
                line(app.UIAxes, [app.Fejes(i) app.Fejes(i)], [0 app.Mejes(i)],...
                     'LineStyle', '-.', 'Color', 'r', 'HandleVisibility','off');
                line(app.UIAxes, app.Fejes(i), app.Mejes(i), 'Marker','o',...
                     'MarkerEdgeColor','m','HandleVisibility','off');
            end
            hold(app.UIAxes, 'off')
            profile off
        end
    
        function frame = segmentacion(app, x)
            % Se cargan los valores de la sementación: largo del segmento (igual al largo de la ventana),
            % número de muestras que se sobrepone la cada segmento de la señal y el tipo de ventana
            % app.WinLength = fix(freq*(app.TWin.Value/1000)); WinLength se calcula en Ventana(app,..)
            % si segmentación(app, x) se reutiliza y no se incluye ventana(app, ...) 
            app.Overlap = fix(app.WinLength*(app.OverKnob.Value/100)); % # muestras de sobreposición de la ventana
            app.HopLength = app.WinLength - app.Overlap;
            nRow = length(x);
            % Número de segementos para la señal con sobreposición y ventana especificada
            app.numHops = floor((nRow-app.WinLength)/app.HopLength) + 1;
            % Repite la ventana una vez por cada segmento 
            app.pWindow = repmat(app.Win, 1, app.numHops);
            % Se segmenta la señal por medio de la creación de una matriz de índices "Idx"
            % sobre la cual se reacomoda la señal elemento a índice de la siguiente forma x(Idx);
            ncol = fix((nRow-app.Overlap)/(app.HopLength)); % # columnas
            % Vector con los avances de índice de cada columna, ej.: para HopLength = 110; y ncol = 97
            % coloffsets = [0 110 220 330 ... 10560]
            app.coloffsets = (0:(ncol-1))*(app.HopLength);
            % Para HopLength = 110 y WinLength = 331, nRow = 11025 con el vector coloffsets anterior
            % Idx = [1, 111, 221, ... 10561; 2, 112, 222, ..., 10562; ...; 331, 441, 551, ... 10891];
            % El restante de la señal se trunca, en este caso 133 muestras de 10892 a 11025
            app.Idx = (1:app.WinLength)'+app.coloffsets;
            % Se segmenta la señal de acuerdo a los índices Idx y se le aplica la ventana a cada segmento
            frame = x(app.Idx).*app.pWindow;    
        end
        
        function Espectro(app, x, fs, axes, titulo)
            cla(axes, 'reset');
            app.Tespectro = (app.coloffsets + (app.nfft/2)')/fs;            
            app.Sxx = abs(fft(x, app.nfft));
            app.fespectro = psdfreqvec('npts', app.nfft, 'Fs', fs, 'Range', 'half');
            [app.Pxx,app.fespectro] = computepsd(app.Sxx, app.fespectro,...
                                                 'onesided', app.nfft, fs);
            imagesc(axes, app.Tespectro, app.fespectro, 10*log10(abs(app.Pxx)+eps))
            axes.YDir = 'normal';
            axes.YTickLabel = axes.YTick/1000;
            h = colorbar(axes);
            h.Label.String = ('Densidad Espetral de Potencia (dB/Hz)');
            xlabel(axes, 'Tiempo (s)');
            ylabel(axes, 'Frecuencia (kHz)');                   
            axes.PlotBoxAspectRatioMode = 'manual';
            axes.PlotBoxAspectRatio(1) = .95;
            axis(axes, 'tight')    
            title(axes, titulo)   
        end
        
        function Ventana(app, tipo, freq)
             app.WinLength = fix(freq*(app.TWin.Value/1000));
             app.nfft = str2double(app.NFFTDropDown.Value);
             switch tipo
                case 'Hamming'
                    app.Win = hamming(app.WinLength);
                    app.BetaKaiser.Enable = 'off';
                case 'Hann'
                    app.Win = hann(app.WinLength);
                    app.BetaKaiser.Enable = 'off';
                case 'Kaiser-Bessel'
                    app.Win = kaiser(app.WinLength, app.BetaKaiser.Value);
                    app.BetaKaiser.Enable = 'on';
                case 'Rectangle'
                    app.Win = rectwin(app.WinLength);
                    app.BetaKaiser.Enable = 'off';
            end
        end
        
        function MelFilterBank(app)
            cla(app.UIAxes2, 'reset');
            app.FilterBank = zeros(app.nfft, app.NFB);
            % (0:app.WinLength-1)/app.WinLength genera un vector de 0 a (WinLength-1)/WinLength ~= 0.9955
            % WinLength = NFFT en este programa, linFq es un vector que relaciona cada punto de la DFT con
            % su correspondiente frecuencia en un rango de 0 a la fs 
            linFq = (0:app.nfft-1)/app.nfft*app.Framedfs;
            N = length(app.Fejes);
            % Determine inflection points
            p = zeros(N, 1);
            % Guarda el índice de la DFT que corresponde a la primer frecuencia mayor que el Eje del filtro en Hz
            for edgeNumber = 1:N
                p(edgeNumber) = find(linFq > app.Fejes(edgeNumber), 1, 'first');
            end  
            % Create triangular filters for each band
            bw = diff(app.Fejes);
            for k = 1:app.NFB
            % Rising side of triangle
                app.FilterBank(p(k):p(k+1)-1,k) = (linFq(p(k):p(k+1)-1) - app.Fejes(k))...
                            / bw(k);    
                % Falling side of triangle
                app.FilterBank(p(k+1):p(k+2)-1,k) = (app.Fejes(k+2) - linFq(p(k+1):...
                           p(k+2)-1)) / bw(k+1);
            end
            numBins = length(app.FilterBank);                
            binIndex = 1:numBins;
            binIncrement = app.Framedfs/numBins;
            app.PlotFBfreq = binIndex*binIncrement;
            plot(app. UIAxes2, app.PlotFBfreq, app.FilterBank)
            xlim(app.UIAxes2, [0 app.LimiteSuperior.Value+100]);
            xlabel(app.UIAxes2, 'Frecuencia (kHz)');
            ylabel(app.UIAxes2, 'Amplitud');
            title(app.UIAxes2, 'Respuesta en frecuencia del banco de filtros Mel')
            app.UIAxes2.XTickLabel = app.UIAxes2.XTick/1000;
        end
        
        function FBNormalization(app)
            cla(app.UIAxes2, 'reset');
            app.nFilterBank = zeros(size(app.FilterBank));
            weightPerBand = sum(app.FilterBank.^2);      
            for i = 1:app.NFB
                app.nFilterBank(:,i) = app.FilterBank(:,i)./weightPerBand(i);
            end
            plot(app.UIAxes2, app.PlotFBfreq, app.nFilterBank)
            axis(app.UIAxes2, [0 app.LimiteSuperior.Value+100 0 inf])
            xlabel(app.UIAxes2, 'Frecuencia (kHz)');
            ylabel(app.UIAxes2, 'Amplitud');
            title(app.UIAxes2, 'Normalizado por Área') 
            app.UIAxes2.XTickLabel = app.UIAxes2.XTick/1000;
        end
        
        function ApplyFB(app)
            cla(app.UIAxes2, 'reset')
            app.MelSpect = app.nFilterBank'*app.Sxx + sqrt(eps('double'));
            imagesc(app.UIAxes2, app.Tespectro, app.Mejes, 10*log10(abs(...
                    app.MelSpect)+eps));
            app.UIAxes2.YDir = 'normal';
            app.UIAxes2.YTickLabel = app.UIAxes2.YTick/1000;
            h = colorbar(app.UIAxes2);
            h.Label.String = ('Densidad Espectral de Potencia (dB/Mel)');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Frecuencia (kMel)');                   
            app.UIAxes2.PlotBoxAspectRatioMode = 'manual';
            app.UIAxes2.PlotBoxAspectRatio(1) = .95;
            axis(app.UIAxes2, 'tight')     
            title(app.UIAxes2, 'Espectrograma en escala Mel')           
        end
        
        function MFCC(app)
            cla(app.UIAxes, 'reset')
            DCT(app);
            temp = app.DCTmatrix * app.MelSpect;
            if app.IncluirLogE.Value               
                app.coeffs = [app.logE; temp];
            else                                  
                app.coeffs = temp;
            end
            if app.IncluirDeltas.Value
                app.Delta = CompDelta(app, app.coeffs);
                if app.IncluirDeltas.Value
                    app.Delta2 = CompDelta(app, app.Delta);
                end
            end
            xl = 'Tiempo (s)';
            yl = 'Coeficientes Cepstrales';
            cl = 'Valor del MFCC';
            titulo = 'Coeficientes Cepstrales en Frecuencia Mel (MFCCs)';
            app.vcoeffs = 1:app.nMFCC.Value;
            PlotSpect(app, app.UIAxes, app.Tespectro, app.vcoeffs, app.coeffs,...
                      xl, yl, cl, titulo)
        end
        
        function DCT(app)
        % Calcula la matriz requerida para aplicar la Transformada Discreta de
        % Coseno 2 unitaria, de acuerdo con las ecuaciones 8.158, 8.159 y 8.160
        % del libro "Discrete Time Signal Processing" de Oppenheim
            N = app.nMFCC.Value;
            K = app.NFB;
            matrix = zeros(N,K);
            A = sqrt(1/K);
            B = sqrt(2/K);
            C = 2*K;
            for n = 1:N
                for k = 1:K
                    if n == 1
                        matrix(n,k) = A*cos(pi*(n-1)*(2*k-1)/C);
                    else
                        matrix(n,k) = B*cos(pi*(n-1)*(2*k-1)/C);
                    end
                end
            end
            app.DCTmatrix = matrix;
        end
        
        function y = CompDelta(~, x)
            % Se fija a 2 la ventana de los elementos involucrados en el cálculo de
            % las diferencias Delta, una mayor ventana implica realizar la diferenciación
            % por medio de un filtro en el dominio cepstral, no he revisado los detalles 
            % del mismo, ni si distorsiona la señal; si se desea ampliar la ventana > 2 debe ser impar       
            M = 1; % instrucción original; floor(DeltaLength/2); con DeltaLength >= 2
            xPadded = cat(1, x(1,:), x, x(end,:));
            temp = diff(xPadded, 1);
            y = temp(M:end-M, :);
        end
        
        function PlotVentana(app)
            x = 0:app.WinLength-1;
            plot(app.UIAxes2, x, app.Win)
            axis(app.UIAxes2, 'tight')
            xlabel(app.UIAxes2, 'Muestras');
            ylabel(app.UIAxes2, 'Amplitud');
            title(app.UIAxes2, 'Ventana seleccionada')   
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
        end
        
        function RefreshWin(app)
            if app.Demuestrear.Value
                freq = app.Dfs;
            else
                freq = app.RecordedFs;
            end
            Ventana(app, app.TipoVentana.Value, freq);
            if app.VVentana.Value
                PlotVentana(app)
            end
        end
        
        function PlotSpect(~, axes, t, f, p, xl, yl, cl, titulo)
            imagesc(axes, t, f, 10*log10(abs(p)+eps))
            axes.YDir = 'normal';
            h = colorbar(axes);
            h.Label.String = (cl);
            xlabel(axes, xl);
            ylabel(axes, yl);                   
            axes.PlotBoxAspectRatioMode = 'manual';
            axes.PlotBoxAspectRatio(1) = .95;
            axis(axes, 'tight')    
            title(axes, titulo) 
        end
        
        function labels = Reconocedor(app, net)
            cla(app.UIAxes, 'reset')
            cla(app.UIAxes3, 'reset')
            classificationRate = 20;
            app.countThreshold = ceil(classificationRate*0.2);
            labels = net.Layers(end).Classes;
            % Inicializa app.YBuffer como variable categórica, si no se inicializa,
            % Matlab trata la variable como tipo double
            app.YBuffer = categorical("No reconocida");
            app.YBuffer(1:classificationRate/2) = categorical("No reconocida");
            app.probBuffer = zeros([numel(labels), classificationRate/2]);
            app.Framedfs = 22050;
            app.audioIn = audioDeviceReader('SampleRate', app.Framedfs,...
                                            'SamplesPerFrame', floor(...
                                            app.Framedfs/classificationRate));
            app.waveBuffer = zeros([app.Framedfs*1.5, 1]);
            app.epsil = 1e-6;
            z = 1:app.Framedfs*1.5;
            app.live = app.audioIn();
            app.waveBuffer(1:end-numel(app.live)) = app.waveBuffer(numel(app.live)...
                                                                   +1:end);  
            app.waveBuffer(end-numel(app.live)+1:end) = app.live;
            spec = TestProcessing(app);
            app.livespeech = line(app.UIAxes, z, app.waveBuffer);
            app.livespec = pcolor(app.UIAxes3, spec);
            axis(app.UIAxes, 'tight');
            axis(app.UIAxes3, 'tight');
            ylim(app.UIAxes, [-0.5 0.5]);
            xlim(app.UIAxes, [0 app.Framedfs]);
            ylim(app.UIAxes3, [1 40]);
            caxis(app.UIAxes3, [-5 0])
            shading(app.UIAxes3, 'flat')
            app.Lamp2.Color = 'Cyan';
            title(app.UIAxes, 'Neuropsi')
        end
        
        function Ver(app, net, labels)
            % Calcula, reconoce y grafica el espectrograma de la entrada del microfono
            app.live = app.audioIn();
            app.waveBuffer(1:end-numel(app.live)) = app.waveBuffer(...
                           numel(app.live)+1:end);
            app.waveBuffer(end-numel(app.live)+1:end) = app.live;
            spec = quickProcessing(app, app.waveBuffer);
            app.livespeech.YData = app.waveBuffer;
            app.livespec.CData = spec;          
            [YPredicted,probs] = classify(net, spec, 'ExecutionEnvironment','cpu');
            app.YBuffer(1:end-1)= app.YBuffer(2:end);
            app.YBuffer(end) = YPredicted;
            app.probBuffer(:,1:end-1) = app.probBuffer(:,2:end);
            app.probBuffer(:,end) = probs';
            [YMode,count] = mode(app.YBuffer);
            maxProb = max(app.probBuffer(labels == YMode,:));
            probThreshold = 0.7;
            if YMode == "background" || count<app.countThreshold || maxProb...
                                                        < probThreshold
                app.Palabra.Text = " ";
            else
            app.Palabra.Text = " ";
            end           
            drawnow limitrate
        end 
        
        function [escala, rango] = Hz2Escala(app, x)
            switch app.EscalaDropDown.Value
                case 'Mel'
                    escala = 2595.*log10(1+x./700);
                    rango = 2595.*log10(1+app.Range./700);
                case 'Bark'
                    escala = 13.*atan(0.00076.*x) + 3.5.*atan((x./7500).^2);
                    rango = 13.*atan(0.00076.*app.Range) + 3.5.*atan((app.Range./7500).^2);
            end
        end
        
        function Escala2Hz(app)
            switch app.EscalaDropDown.Value
                case 'Mel'
                    app.Fejes = 700.*((10.^(app.Mejes/2595))-1);
                case 'Bark'
                    app.Fejes = 650.*sinh(abs(app.Mejes)./7);
            end
        end
        
        function AumentarListaPalabras(app)
            for i = 1:height(app.main)
                name = string(app.main.Properties.RowNames(i));
                vdir = fullfile(app.dirname, name, strcat(name, '.csv'));
                volu = readtable(vdir);
                n = height(volu); 
                nD = length(app.Diccionario);
                if n < nD
                    words = app.Diccionario(n+1:nD);
                    a = zeros(nD-n, 1);
                    temp = table(a, words, a, a, 'VariableNames', app.volname);
                    augmented = [volu; temp];
                    writetable(augmented, vdir, 'WriteRowNames',true)
                end
            end
        end
        
        function processed = TTProcessing(app)
            filtered = filter(app.filtro, app.waveBuffer);
            dsampled = downsample(filtered, 2);
            framed = dsampled(s.Idx).*s.pWindow;
            spec = abs(fft(framed, s.nfft));
            scaled = s.nFilterBank'*spec + sqrt(eps('double'));
            processed = log10(scaled + eps);
        end
        
        function VerTest(app, net, labels)
            app.live = app.audioIn();
            app.waveBuffer(1:end-numel(app.live)) = app.waveBuffer(...
                           numel(app.live)+1:end);
            app.waveBuffer(end-numel(app.live)+1:end) = app.live;
            spec = TestProcessing(app);
            app.livespeech.YData = app.waveBuffer;
            app.livespec.CData = spec;          
            [YPredicted,probs] = classify(net, spec, 'ExecutionEnvironment','cpu');
            app.YBuffer(1:end-1)= app.YBuffer(2:end);
            app.YBuffer(end) = YPredicted;
            app.probBuffer(:,1:end-1) = app.probBuffer(:,2:end);
            app.probBuffer(:,end) = probs';
            [YMode,count] = mode(app.YBuffer);           
            maxProb = max(app.probBuffer(labels == YMode,:));
            probThreshold = 0.7;
            if count<app.countThreshold || maxProb < probThreshold
                app.Palabra.Text = "";
            else
                app.Palabra.Text = char(YMode);                
            end           
            drawnow limitrate
        end
              
        function spec = TestProcessing(app)
           frameDuration = 0.025;
           hopDuration = 0.010;
           app.numBands = 40;
           frameLength = round(frameDuration*app.Framedfs);
           hopLength = round(hopDuration*app.Framedfs);
           spec = auditorySpectrogram(app.waveBuffer, app.Framedfs, ...
                                      'WindowLength', frameLength, ...
                                      'OverlapLength',frameLength-hopLength, ...
                                      'NumBands', app.numBands, ...
                                      'Range', [50,7000], 'WindowType','Hann', ...
                                      'WarpType','Bark', 'SumExponent',2);
           spec = log10(spec + app.epsil);
        end
        
        function memoria(app, net)
            sonido = audioplayer(app.words3{1}, 22050); % Instrucciones
            playblocking(sonido)
            sonido = audioplayer(app.words1{1}, 22050); % Gato
            playblocking(sonido)
            sonido = audioplayer(app.words1{2}, 22050); % Pera
            playblocking(sonido)
            sonido = audioplayer(app.words1{3}, 22050); % Mano
            playblocking(sonido)
            sonido = audioplayer(app.words1{4}, 22050); % Fresa
            playblocking(sonido)
            sonido = audioplayer(app.words1{5}, 22050); % Vaca
            playblocking(sonido)
            sonido = audioplayer(app.words1{6}, 22050); % Codo
            playblocking(sonido)
            labels = Reconocedor(app, net);
            app.Palabra.Text = '';                
            for i = 1:240
                VerTest(app, net, labels)     
            end 
            app.Lamp2.Color = [0.9, 0.9, 0.9];
            app.Palabra.Text = '';
            sonido = audioplayer(app.words1{3}, 22050); % Mano
            playblocking(sonido)
            sonido = audioplayer(app.words1{5}, 22050); % Vaca
            playblocking(sonido)
            sonido = audioplayer(app.words1{4}, 22050); % Fresa
            playblocking(sonido)
            sonido = audioplayer(app.words1{1}, 22050); % Gato
            playblocking(sonido)
            sonido = audioplayer(app.words1{6}, 22050); % Codo
            playblocking(sonido)
            sonido = audioplayer(app.words1{2}, 22050); % Pera
            playblocking(sonido)
            for i = 1:240
                VerTest(app, net, labels)     
            end
            app.Lamp2.Color = [0.9, 0.9, 0.9];   
            app.Palabra.Text = '';
            sonido = audioplayer(app.words1{6}, 22050); % Codo
            playblocking(sonido)
            sonido = audioplayer(app.words1{4}, 22050); % Fresa
            playblocking(sonido)
            sonido = audioplayer(app.words1{2}, 22050); % Pera
            playblocking(sonido)
            sonido = audioplayer(app.words1{6}, 22050); % Codo
            playblocking(sonido)
            sonido = audioplayer(app.words1{1}, 22050); % Gato
            playblocking(sonido)
            sonido = audioplayer(app.words1{3}, 22050); % Mano
            playblocking(sonido)
            for i = 1:240
                VerTest(app, net, labels)     
            end
            app.Lamp2.Color = [0.9, 0.9, 0.9];
        end
        
        function labels = DemoRec(app, net)
            cla(app.UIAxes, 'reset')
            cla(app.UIAxes3, 'reset')
            classificationRate = 20;
            app.countThreshold = ceil(classificationRate*0.2);
            labels = net.Layers(end).Classes;
            % Inicializa app.YBuffer como variable categórica, si no se inicializa,
            % Matlab trata la variable como tipo double
            app.YBuffer = categorical("No reconocida");
            app.YBuffer(1:classificationRate/2) = categorical("No reconocida");
            app.probBuffer = zeros([numel(labels), classificationRate/2]);
            app.Framedfs = 22050;
            app.audioIn = audioDeviceReader('SampleRate', app.Framedfs,...
                                            'SamplesPerFrame', floor(...
                                            app.Framedfs/classificationRate));
            app.waveBuffer = zeros([app.Framedfs*1.5, 1]);
            app.epsil = 1e-6;
            z = 1:app.Framedfs*1.5;
            app.live = app.audioIn();
            app.waveBuffer(1:end-numel(app.live)) = app.waveBuffer(numel(app.live)...
                                                                   +1:end);  
            app.waveBuffer(end-numel(app.live)+1:end) = app.live;
            spec = TestProcessing(app);
            app.livespeech = line(app.UIAxes, z, app.waveBuffer);
            app.livespec = pcolor(app.UIAxes3, spec);
            axis(app.UIAxes, 'tight');
            axis(app.UIAxes3, 'tight');
            ylim(app.UIAxes, [-0.5 0.5]);
            xlim(app.UIAxes, [0 app.Framedfs]);
            ylim(app.UIAxes3, [1 40]);
            caxis(app.UIAxes3, [-5 0])
            shading(app.UIAxes3, 'flat')
            app.Lamp2.Color = 'Cyan';
            title(app.UIAxes, 'Reconocimiento de voz continuo')
            while strcmpi(app.ActivarSwitch.Value, 'On')
               VerTest(app, net, labels)
            end
        end
        
        function repeticion(app, net)
            sonido = audioplayer(app.words3{2}, 22050); % Instrucciones
            playblocking(sonido)
            sonido = audioplayer(app.words2{1}, 22050); % Sol
            playblocking(sonido)
            labels = Reconocedor(app, net);
            for i = 1:50
                VerTest(app, net, labels)     
            end
            sonido = audioplayer(app.words2{2}, 22050); % Ventana
            playblocking(sonido)
            for i = 1:50
                VerTest(app, net, labels)     
            end
            sonido = audioplayer(app.words2{3}, 22050); % Ventana
            playblocking(sonido)
            for i = 1:90
                VerTest(app, net, labels)     
            end
            sonido = audioplayer(app.words2{4}, 22050); % Ventana
            playblocking(sonido)
            for i = 1:200
                VerTest(app, net, labels)     
            end
        end
        
        function fluidez(app, net)
            sonido = audioplayer(app.words3{3}, 22050); % Ventana
            playblocking(sonido)
            labels = Reconocedor(app, net);
            for i = 1:1100
                VerTest(app, net, labels)     
            end
            net = app.Net3;
            sonido = audioplayer(app.words3{4}, 22050); % Ventana
            playblocking(sonido)
            for i = 1:1100
                VerTest(app, net, labels)     
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, weights, NumCNN)
            clc;
            app.varname = {'ID' 'Edad' 'Sexo' 'Escolaridad'};
            app.volname = {'Diccionario' 'Registro' 'Procesado'};
            app.mainfilename = 'main.csv'; % Registro de palabras por persona
            app.Diccionario = {'Gato'; 'Pera'; 'Mano'; 'Fresa'; 'Vaca'; 'Codo';...
                               'Sol'; 'Ventana'; 'El'; 'Niño'; 'Llora'; 'Hombre';...
                               'Camina'; 'Lentamente'; 'Por'; 'La'; 'Calle';...
                               'León'; 'Caballo'; 'Toro'; 'Perro'; 'Cabra';
                               'Pájaro'; 'Pollo'; 'Cerdo'; 'Paloma'; 'Rata';...
                               'Cordero'; 'Gallo'; 'Ratón'; 'Mono'; 'Pez'; 'Gallina';...
                               'Serpiente'; 'Tigre'; 'Oso'; 'Canario'; 'Fue';...
                               'Forma'; 'Fueron'; 'Frente'; 'Fin'; 'Fuera'; 'Final';...
                               'Familia'; 'Falta'; 'Fuerza'; 'Fondo'; 'Futuro';...
                               'Figura'; 'Fuerte'; 'Fuente'; 'Función'; 'Favor';...
                               'Formación'; 'Fecha'; 'Fácil'};
            if nargin == 1 % Se llama la aplicación sola
                app.TabGroup.SelectedTab = app.AdquisicinTab;
                app.VCeros = zeros(length(app.Diccionario), 1);
                app.VLogical = logical(app.VCeros);
                text = sprintf('%s\n%s','Ingresa los datos del voluntario así como la carpeta',...
                           'donde se guardarán las grabaciones, luego presiona "Listo"');
                app.Mensaje.Text = (text);
                text = strcat('Factor', ' ', char(946));
                app.BetaKaiserLabel.Text = text;
                text = sprintf('%s\n%s', 'Ancho de banda', '      en escala');
                app.Label_3.Text = strjust(text);
                app.AjustesTab.Visible = 'off';
            elseif nargin == 2 % Se ingresa la matriz entrenada de la Red Neuronal
                app.trainedNet = weights;
                app.NumCNN = NumCNN;
                app.ReconocerButton.Enable = 'on';
                app.TabGroup.SelectedTab = app.ReconocedorTab;
            end
            app.Lamp2.Color = [0.9, 0.9, 0.9];
        end

        % Value changed function: Apagar
        function ApagarValueChanged(app, event)
            salir = uiconfirm(app.UIFigure,'¿Realmente quieres salir?',...
                              'Confirmar Salida', 'Icon','warning');
            if strcmpi(salir,'ok')
                writetable(app.main, app.mainfile, 'WriteRowNames',true);
                delete(app);
            end
        end

        % Button pushed function: DirSelect
        function DirSelectButtonPushed(app, event)
            app.dirname = uigetdir('C:\', 'Selecciona el directorio de la base de datos');
            % Existe un bug que ocasiona que después de usar uigetdir no se regrese a la
            % pantalla de la aplicación, el encender y apagar la propiedad de visibilidad
            % de toda la aplicación es un parche a este problema
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: Grabar
        function GrabarValueChanged(app, event)
            if strcmpi(app.Grabar.Value,'on')
                [~, app.I] = min(app.volun.Registro);
                app.Palabra.Text = app.volun.Diccionario(app.I);
                app.Mensaje.Text = 'A la cuenta de 3 pronuncia la siguiente palabra:';
                app.Status.Text = 'Grabando en 3';
                j = 2;
                for i = 0:2
                    app.Lamp.Color = 'yellow';
                    pause(0.4)
                    app.Lamp.Color = [0.9, 0.9, 0.9];
                    pause(0.3)
                    % strcat elimina los espacios a menos que se use sintaxis de 
                    app.Status.Text = strcat('Grabando en: ', {' '}, num2str(j));
                    j = j-1;
                end
                fs = str2double(app.fMuestreo.Value);
                nBits = str2double(app.Resolucion.Value);
                app.audio = audiorecorder(fs,nBits,1);
                app.Lamp.Color = 'red';
                app.Status.Text = 'Grabando';
                recordblocking(app.audio, app.Duracion.Value);
                app.Lamp.Color = [0.9, 0.9, 0.9];
                app.Status.Text = 'Apagado';
                app.Grabar.Value = 'Off';
                app.signal = getaudiodata(app.audio);
                tiempo=linspace(0, app.Duracion.Value, numel(app.signal));
                plot(app.UIAxes, tiempo, app.signal)
                app.Reproducir.Enable = 'on';
                app.Guardar.Enable = 'on';
            end
        end

        % Button pushed function: Guardar
        function GuardarPushed(app, event)
        % temp es la ruta de acceso a la carpeta donde se guarda las grabaciones de la palabra
            temp = string(fullfile(app.dirname, app.Nombre.Value, app.Diccionario(app.I)));
            % isempty no funciona ya que evalúa app.volun.Registro(app.I) como cell con array numérico
            % accesado como app.volun(app.I, 'Registro) tampoco funciona ya que se devuelve un dato
            % tipo tabla, el cual a su vez contiene un cell con array vacío; aparentemente el
            % operador == si evalúa el contenido de la tabla cuando se accesa por notación de puntos
            % sin embargo no está definico en notación de paréntesis
            if app.volun.Registro(app.I) == 0
                mkdir(temp)
            end
            i = app.volun.Registro(app.I) + 1;
            % wavname es el nombre del archivo wav de cada grabacion por palabra: 1.wav, 2.wav, etc
            wavname = strcat(num2str(i), '.wav');
            app.wavfile = fullfile(temp, wavname);
            % Alerta si ocurrió un error al escribir el archivo de audio
            try
            audiowrite(app.wavfile, app.signal, str2double(app.fMuestreo.Value),...
                       'BitsPerSample', str2double(app.Resolucion.Value));
            catch
                uialert(app.UIFigure, 'Algo salío mal al guardar la grabación',...
                        'Error al guardar el audio');
                return
            end
            app.volun.Registro(app.I) = app.volun.Registro(app.I) + 1;
            app.Reproducir.Enable = 'off';
            app.Guardar.Enable = 'off';
            writetable(app.volun, app.volfile, 'WriteRowNames',true);
            app.Mensaje.Text = '¡Archivo Guardado exitosamente!';
            cla(app.UIAxes);
            pause (1);
            app.Mensaje.Text = 'Listo para continuar grabando';
            app.Palabra.Text = '';
            app.AjustesTab.Visible = 'on';
        end

        % Button pushed function: Reproducir
        function ReproducirPushed(app, event)
            app.Lamp.Color = 'green';
            app.Status.Text = 'Reproduciendo';
            sonido = audioplayer(app.signal, str2double(app.fMuestreo.Value));
            playblocking(sonido)
            app.Lamp.Color = [0.9, 0.9, 0.9];
            app.Status.Text = 'Apagado';
        end

        % Value changed function: Ready2Rec
        function Ready2RecValueChanged(app, event)
            %{ 
            Recording switch gets disable by default, this checkbox could enable it if
            conditions are met: if set-up values are given and checkbox is true,
            switch gets enabled meanwhile config panel gets disabled.
            Checked values are: name and storage directory; if one is empty then 
            throws a msgbox asking for the missing value.
            When checkbox is False, deactivates switch and enables config panel.
            %}
            value = app.Ready2Rec.Value;
            nombre = ~isempty(app.Nombre.Value);
            dir = ~isempty(app.dirname);
            if value && nombre && dir
                app.Grabar.Enable = 'on';
                app.Nombre.Enable = 'off';
                app.fMuestreo.Enable = 'off';
                app.Resolucion.Enable = 'off';
                app.Edad.Enable = 'off';
                app.Duracion.Enable = 'off';
                app.DirSelect.Enable = 'off';
                app.Sexo.Enable = 'off';
                app.Escolaridad.Enable = 'off';
                Iniciar(app);
                text = sprintf('%s\n%s','Acciona el switch izquierdo para comenzar a',...
                               ' grabar después de la cuenta regresiva');
                app.Mensaje.Text = (text);  
            else
                if ~nombre
                    uialert(app.UIFigure, 'Introduce los datos del voluntario',...
                            'Adquisición de voz', 'Icon', 'info');
                end
                if ~dir
                    uialert(app.UIFigure, 'Selecciona la carpeta de la base de datos',...
                            'Adquisición de voz', 'Icon', 'info');
                end
                app.Grabar.Enable = 'off';
                app.Nombre.Enable = 'on';
                app.fMuestreo.Enable = 'on';
                app.Resolucion.Enable = 'on';
                app.Edad.Enable = 'on';
                app.Duracion.Enable = 'on';
                app.DirSelect.Enable = 'on';
                app.Sexo.Enable = 'on';
                app.Escolaridad.Enable = 'on';
                app.Reproducir.Enable = 'off';
                app.Guardar.Enable = 'off';
                app.Ready2Rec.Value = 0;                
            end          
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            if strcmpi(selectedTab, 'Patrones')
                uialert(app.UIFigure,'Entra aquí sólo si sabes lo que estas haciendo',...
                        'Procesamiento de señales', 'Icon','warning');
                app.Grabar.Enable = 'off';
                app.Ready2Rec.Value = false;
                a = fix(str2double(app.fMuestreo.Value)/2);
                b = a/2;
                app.FCorteH.Limits = [0 a];
                app.FCorteH.Limits = [0 b];
                app.FCorteH.Value = b;
            elseif strcmpi(selectedTab, 'Reconocedor')
                if isempty(app.CNN)                    
                    app.CNN = load('D:\dfgr0\Google Drive\Base de Datos\Net.mat'); 
                    app.Net1 = app.CNN.CNNin{1};
                    app.Net2 = app.CNN.CNNin{2};
                    app.Net3 = app.CNN.CNNin{3};
                    records = load('D:\dfgr0\Google Drive\Base de Datos\records.mat');
                    app.words1 = records.records.first;
                    app.words2 = records.records.seconda;
                    app.words3 = records.records.third;
                end
                app.Mensaje.Text = '';
            elseif strcmpi(selectedTab, 'Adquisición')
                cla(app.UIAxes, 'reset')
                title(app.UIAxes, 'Adquisición de Voz');
                app.Palabra.Text = '';
            end
        end

        % Value changed function: Bode
        function BodeValueChanged(app, event)
            cla(app.UIAxes, 'reset');
            if app.Bode.Value
                [H, F] = freqz(app.filtro, 512, app.RecordedFs);
                yyaxis (app.UIAxes, 'left')
                [F,~,uf] = engunits(F,'unicode');
                freqlbl = getfreqlbl([uf 'Hz']);
                plot(app.UIAxes, F, 10*log10(abs(H(1:512))));
                xlabel(app.UIAxes, freqlbl);
                ylabel(app.UIAxes,'Magnitud (dB)');
                xlim(app.UIAxes, [0 max(F)])
                ylim(app.UIAxes, [-inf max(abs(H))+5])
                yyaxis (app.UIAxes, 'right')
                PHI = phasez(app.filtro, 512, app.RecordedFs);
                plot(app.UIAxes, F, PHI)
                ylim(app.UIAxes, [min(PHI)-20 max(PHI)+77])
                ylabel(app.UIAxes, 'Fase (rads)');
                app.Bode.Text = 'Mostrar señal filtrada';
                title(app.UIAxes, 'Respuesta en Frecuencia del filtro');
            else
                plot(app.UIAxes, app.t, [app.signal, app.filtrada]);
                xlabel(app.UIAxes, 'Tiempo (s)');  
                legend(app.UIAxes, 'Original', 'Filtradad')
                title(app.UIAxes, 'Señal Filtrada vs Señal Original')
                app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            end
        end

        % Value changed function: TipoVentana
        function TipoVentanaValueChanged(app, event)
            RefreshWin(app)
        end

        % Value changed function: BetaKaiser
        function BetaKaiserValueChanged(app, event)
            RefreshWin(app)
        end

        % Button pushed function: AplicarPre
        function AplicarPreButtonPushed(app, event)
            dir = app.wavfile;
            Filter(app, dir)
            app.Bode.Enable = 'on';
            app.VVentana.Enable = 'on';
        end

        % Value changed function: Demuestrear
        function DemuestrearValueChanged(app, event)
            if app.Demuestrear.Value
                app.Dsample = downsample(app.filtrada, 2);
                app.Dfs = app.RecordedFs/2;
                RefreshWin(app)
                app.Framed = segmentacion(app, app.Dsample);
                Espectro(app, app.Framed, app.Dfs, app.UIAxes2,...
                         'Señal Submuestreada')
            else
                RefreshWin(app)
                app.Framed = segmentacion(app, app.filtrada);
                Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2,...
                         'Señal Filtrada')
            end            
        end

        % Button pushed function: GuardarEspButton
        function GuardarEspButtonPushed(app, event)
            if app.Demuestrear.Value
                app.Framedfs = app.Dfs;
            else
                app.Framedfs = app.RecordedFs;
            end
        end

        % Value changed function: ParametroFB
        function ParametroFBValueChanged(app, event)
            if strcmpi(app.ParametroFB.Value,'Bandwidth')
                app.MelBandwidth.Enable = 'on';
                app.NMelFilter.Enable = 'off';                
                app.NFB = 0;
                filterBankParameters(app)
            else
                app.MelBandwidth.Enable = 'off';
                app.NMelFilter.Enable = 'on';
                app.FBBandwidth = 0;
                filterBankParameters(app)
            end
        end

        % Value changed function: LimiteInferior
        function LimiteInferiorValueChanged(app, event)
            filterBankParameters(app)
        end

        % Value changed function: LimiteSuperior
        function LimiteSuperiorValueChanged(app, event)
            filterBankParameters(app)
        end

        % Value changed function: NMelFilter
        function NMelFilterValueChanged(app, event)
            filterBankParameters(app)
        end

        % Value changed function: MelBandwidth
        function MelBandwidthValueChanged(app, event)
            filterBankParameters(app)
        end

        % Button pushed function: GenFBButton
        function GenFBButtonPushed(app, event)
            MelFilterBank(app)
            app.NormalizarButton.Enable = 'on';
        end

        % Value changed function: IncluirLogE
        function IncluirLogEValueChanged(app, event)
            app.logE = log(sum(app.Sxx.^2)/app.WinLength);
            MFCC(app)
        end

        % Value changed function: IncluirDeltas
        function IncluirDeltasValueChanged(app, event)
            app.IncluirDeltas2do.Enable = 'on';
            app.MostrarDeltaButton.Enable = 'on';
            MFCC(app)
        end

        % Value changed function: VVentana
        function VVentanaValueChanged(app, event)
            cla(app.UIAxes2, 'reset');
            if app.VVentana.Value
                RefreshWin(app)
            else
                plot(app.UIAxes2, app.Fp, 10*log10(app.P))
                axis(app.UIAxes2, 'tight')
                xlabel(app.UIAxes2, 'Frecuencia (Hz)');
                ylabel(app.UIAxes2, 'Potencia espectral (dB)');
                oracion = "Filtro pasabadas con cortes a: %d y %d Hz";
                title(app.UIAxes2, sprintf(oracion, app.Cutoff(1), app.Cutoff(2)))    
                app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
            end
        end

        % Value changed function: TWin
        function TWinValueChanged(app, event)
            RefreshWin(app)
        end

        % Selection change function: AjustesTab
        function AjustesTabSelectionChanged(app, event)
            selectedTab = app.AjustesTab.SelectedTab.Title;
            switch selectedTab
                case 'Espectro'
                    app.Demuestrear.Value = false;
                    RefreshWin(app)
                    app.Framed = segmentacion(app, app.signal);
                    Espectro(app, app.Framed, app.RecordedFs, app.UIAxes,...
                             'Señal Original')
                    app.Framed = segmentacion(app, app.filtrada);
                    Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2,...
                             'Señal Filtrada')
                case 'Filtro Mel'
                    cla(app.UIAxes, 'reset');
                    app.LimiteInferior.Value = app.Cutoff(1);
                    app.LimiteSuperior.Value = app.Cutoff(2);
                    app.LimiteSuperior.Limits = [app.Cutoff(1) app.Cutoff(2)];
                    app.LimiteInferior.Limits = [0 app.Cutoff(2)];                   
                    filterBankParameters(app);
                case 'MFCC'
                    % MFCC(app)
            end
        end

        % Value changed function: OverKnob
        function OverKnobValueChanged(app, event)
            if app.Demuestrear.Value
                RefreshWin(app)
                app.Framed = segmentacion(app, app.Dsample);
                Espectro(app, app.Framed, app.Dfs, app.UIAxes2,...
                         'Señal Filtrada Demuestrada')
            else
                RefreshWin(app)
                app.Framed = segmentacion(app, app.signal);
                Espectro(app, app.Framed, app.RecordedFs, app.UIAxes,...
                         'Señal Original')
                app.Framed = segmentacion(app, app.filtrada);
                Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2,...
                         'Señal Filtrada')
            end
        end

        % Value changed function: NFFTDropDown
        function NFFTDropDownValueChanged(app, event)
            if app.Demuestrear.Value
                RefreshWin(app)
                app.Framed = segmentacion(app, app.Dsample);
                Espectro(app, app.Framed, app.Dfs, app.UIAxes2,...
                         'Señal Filtrada Demuestrada')
            else
                RefreshWin(app)
                app.Framed = segmentacion(app, app.signal);
                Espectro(app, app.Framed, app.RecordedFs, app.UIAxes,...
                         'Señal Original')
                app.Framed = segmentacion(app, app.filtrada);
                Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2,...
                         'Señal Filtrada')
            end
        end

        % Button pushed function: NormalizarButton
        function NormalizarButtonPushed(app, event)
            FBNormalization(app)
            app.EspectroenescalaButton.Enable = 'on';
        end

        % Value changed function: nMFCC
        function nMFCCValueChanged(app, event)
            value = app.nMFCC.Value;
            if value > app.NFB
                text = 'El número de coeficientes no puede ser mayor que el número de bandas del banco de filtros';
                uialert(app.UIFigure, text, 'Error');
                app.nMFCC.Value = app.NFB;
            end
            MFCC(app)
        end

        % Value changed function: MostrarEspectroButton
        function MostrarEspectroButtonValueChanged(app, event)
            if app.MostrarEspectroButton.Value
                xl = 'Tiempo (s)';
                yl = 'Frecuencia (kHz)';
                cl = 'Densidad Espectral de Potencia (dB/Hz)';
                titulo = 'Espectrograma';
                PlotSpect(app, app.UIAxes2, app.Tespectro, app.fespectro, app.Pxx,...
                          xl, yl, cl, titulo)
                app.UIAxes2.YTickLabel = app.UIAxes2.YTick/1000;
            else
                xl = 'Tiempo (s)';
                yl = 'Frecuencia Mel (kMel)';
                cl = 'Densidad Espectral de Potencia (dB/Mel)';
                titulo = 'Espectrograma en escala Mel';
                PlotSpect(app, app.UIAxes2, app.Tespectro, app.Mejes, app.MelSpect,...
                          xl, yl, cl, titulo)
                app.UIAxes2.YTickLabel = app.UIAxes2.YTick/1000;
            end
        end

        % Value changed function: MostrarDeltaButton
        function MostrarDeltaButtonValueChanged(app, event)
            if app.MostrarDeltaButton.Value
                xl = 'Tiempo (s)';
                yl = 'Coeficientes Delta';
                cl = 'Valor del Coeficiente Delta';
                titulo = 'Coeficientes Delta';
                PlotSpect(app, app.UIAxes, app.Tespectro, app.vcoeffs, app.Delta,...
                          xl, yl, cl, titulo)
            else
                xl = 'Tiempo (s)';
                yl = 'Coeficientes Cepstrales';
                cl = 'Valor del MFCC';
                titulo = 'Coeficientes Cepstrales en Frecuencia Mel (MFCCs)';
                PlotSpect(app, app.UIAxes, app.Tespectro, app.vcoeffs, app.coeffs,...
                          xl, yl, cl, titulo)
            end
        end

        % Value changed function: MostrarDeltaDeltaButton
        function MostrarDeltaDeltaButtonValueChanged(app, event)
            if app.MostrarDeltaDeltaButton.Value
                xl = 'Tiempo (s)';
                yl = 'Coeficientes Delta Delta';
                cl = 'Valor del Coeficiente Delta Delta';
                titulo = 'Coeficientes Delta Delta';
                PlotSpect(app, app.UIAxes, app.Tespectro, app.vcoeffs, app.Delta,...
                          xl, yl, cl, titulo)
            else
                xl = 'Tiempo (s)';
                yl = 'Coeficientes Cepstrales';
                cl = 'Valor del MFCC';
                titulo = 'Coeficientes Cepstrales en Frecuencia Mel (MFCCs)';
                PlotSpect(app, app.UIAxes, app.Tespectro, app.vcoeffs, app.coeffs,...
                          xl, yl, cl, titulo)
            end
        end

        % Value changed function: IncluirDeltas2do
        function IncluirDeltas2doValueChanged(app, event)
            app.MostrarDeltaDeltaButton.Enable = 'on';
            MFCC(app)
        end

        % Value changed function: ActivarSwitch
        function ActivarSwitchValueChanged(app, event)
            if strcmpi(app.ActivarSwitch.Value, 'On')
                app.RedNeuronalSwitch.Enable = 'off';
                app.SelecTest.Enable = 'off';
                app.SelecPrueba.Enable = 'off';
                if strcmp(app.RedNeuronalSwitch.Value, 'TT')
                    if strcmp(app.SelecPrueba.Value, '1')
                        memoria(app, app.Net1)
                        app.ActivarSwitch.Value, 'Off';
                    elseif strcmp(app.SelecPrueba.Value, '2')
                        repeticion(app, app.Net1)
                        app.ActivarSwitch.Value, 'Off';
                    else
                        fluidez(app, app.Net2)
                        app.ActivarSwitch.Value, 'Off';
                    end
                else
                    if strcmp(app.SelecTest.Value, '1')
                        DemoRec(app, app.Net1)
                    elseif strcmp(app.SelecTest.Value, '2')
                        DemoRec(app, app.Net2)
                    else
                        DemoRec(app, app.Net3)
                    end
                end                   
            else
                app.RedNeuronalSwitch.Enable = 'on';
                if strcmp(app.RedNeuronalSwitch.Value, 'TT')
                    app.SelecPrueba.Enable = 'on';
                else
                    app.SelecTest.Enable = 'on';
                end           
                app.Lamp2.Color = [0.9, 0.9, 0.9];
            end 
        end

        % Value changed function: EscalaDropDown
        function EscalaDropDownValueChanged(app, event)
            filterBankParameters (app)
        end

        % Value changed function: EspectroenescalaButton
        function EspectroenescalaButtonValueChanged(app, event)
            ApplyFB(app)
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            if ~exist(fullfile(app.dirname, 'Procesamiento.m'),'file')
                app.processdir = uigetdir('C:\', 'Selecciona el directorio del script de procesamiento');
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
            else
                app.processdir = app.dirname;
            end
            features = fullfile(app.processdir, 'Procesconfig.mat');
            data = struct; data.('filtro') = app.filtro; data.('Idx') = app.Idx; 
            data.('nfft') = app.nfft; data.('nFilterBank') = app.nFilterBank; 
            data.('pWindow') = app.pWindow; data.('numBands') = app.numBands; 
            data.('numHops') = app.numHops; data.('processdir') = app.processdir;
            save(features, 'data')
            pdir = fullfile(app.processdir, 'Procesamiento.m');
            run(pdir);
        end

        % Value changed function: RedNeuronalSwitch
        function RedNeuronalSwitchValueChanged(app, event)
            if strcmpi(app.RedNeuronalSwitch.Value, 'Test')
                app.SelecTest.Enable = 'on';
                app.SelecPrueba.Enable = 'off';
            elseif strcmpi(app.RedNeuronalSwitch.Value, 'TT')
                app.SelecPrueba.Enable = 'on';
                app.SelecTest.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Reproducir = uibutton(app.UIFigure, 'push');
            app.Reproducir.ButtonPushedFcn = createCallbackFcn(app, @ReproducirPushed, true);
            app.Reproducir.Enable = 'off';
            app.Reproducir.Text = 'Reproducir';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Adquisición de Voz')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Intensidad')
            app.Lamp = uilamp(app.UIFigure);
            app.Guardar = uibutton(app.UIFigure, 'push');
            app.Guardar.ButtonPushedFcn = createCallbackFcn(app, @GuardarPushed, true);
            app.Guardar.Enable = 'off';
            app.Guardar.Text = 'Guardar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AdquisicinTab = uitab(app.TabGroup);
            app.AdquisicinTab.Title = 'Adquisición';
            app.FrecuenciademuestreoDropDownLabel = uilabel(app.AdquisicinTab);
            app.FrecuenciademuestreoDropDownLabel.Text = 'Frecuencia de muestreo';
            app.fMuestreo = uidropdown(app.AdquisicinTab);
            app.fMuestreo.Items = {'8000 Hz', '11025 Hz', '22050 Hz', '44100 Hz'};
            app.fMuestreo.ItemsData = {'8000', ' 11025', ' 22050', ' 44100'};
            app.fMuestreo.Value = ' 22050';
            app.NombreEditFieldLabel = uilabel(app.AdquisicinTab);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.Nombre = uieditfield(app.AdquisicinTab, 'text');
            app.EdadEditFieldLabel = uilabel(app.AdquisicinTab);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.Edad = uieditfield(app.AdquisicinTab, 'numeric');
            app.Edad.Limits = [0 35];
            app.Edad.ValueDisplayFormat = '%.0f';
            app.Edad.Value = 18;
            app.DuracinsegLabel = uilabel(app.AdquisicinTab);
            app.DuracinsegLabel.Text = 'Duración (seg)';
            app.Duracion = uieditfield(app.AdquisicinTab, 'numeric');
            app.Duracion.Limits = [1 5];
            app.Duracion.ValueDisplayFormat = '%.1f';
            app.Duracion.Value = 1.5;
            app.ResolucinDropDownLabel = uilabel(app.AdquisicinTab);
            app.ResolucinDropDownLabel.Text = 'Resolución';
            app.Resolucion = uidropdown(app.AdquisicinTab);
            app.Resolucion.Items = {'8 Bits', '16 Bits'};
            app.Resolucion.ItemsData = {'8', '16'};
            app.Resolucion.Value = '16';
            app.DirSelect = uibutton(app.AdquisicinTab, 'push');
            app.DirSelect.ButtonPushedFcn = createCallbackFcn(app, @DirSelectButtonPushed, true);
            app.DirSelect.Text = 'Seleccionar carpeta';
            app.Ready2Rec = uicheckbox(app.AdquisicinTab);
            app.Ready2Rec.ValueChangedFcn = createCallbackFcn(app, @Ready2RecValueChanged, true);
            app.Ready2Rec.Text = 'Listo';
            app.SexoDropDownLabel = uilabel(app.AdquisicinTab);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.Sexo = uidropdown(app.AdquisicinTab);
            app.Sexo.Items = {'Mujer', 'Hombre'};
            app.Sexo.Value = 'Mujer';
            app.VoluntarioLabel = uilabel(app.AdquisicinTab);
            app.VoluntarioLabel.Text = 'Datos del Voluntario';
            app.EscolaridadDropDownLabel = uilabel(app.AdquisicinTab);
            app.EscolaridadDropDownLabel.Text = 'Escolaridad';
            app.Escolaridad = uidropdown(app.AdquisicinTab);
            app.Escolaridad.Items = {'Preparatoria', 'Licenciatura', 'Posgrado'};
            app.Escolaridad.Value = 'Licenciatura';
            app.AudioLabel = uilabel(app.AdquisicinTab);
            app.AudioLabel.Text = 'Configuración de audio';
            app.PatronesTab = uitab(app.TabGroup);
            app.PatronesTab.Title = 'Patrones';
            app.UIAxes2 = uiaxes(app.PatronesTab);
            title(app.UIAxes2, 'Extracción de Características')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AjustesTab = uitabgroup(app.PatronesTab);
            app.AjustesTab.SelectionChangedFcn = createCallbackFcn(app, @AjustesTabSelectionChanged, true);
            app.PreprocesamientoTab = uitab(app.AjustesTab);
            app.PreprocesamientoTab.Title = 'Preprocesamiento';
            app.FiltroTitle = uilabel(app.PreprocesamientoTab);
            app.FiltroTitle.Text = 'Filtro Pasabandas';
            app.FCorteL = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.FCorteL.Value = 300;
            app.FCorteH = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.FCorteH.Value = 3750;
            app.Bode = uibutton(app.PreprocesamientoTab, 'state');
            app.Bode.ValueChangedFcn = createCallbackFcn(app, @BodeValueChanged, true);
            app.Bode.Enable = 'off';
            app.Bode.Text = 'Ver respuesta en frecuencia';
            app.AplicarPre = uibutton(app.PreprocesamientoTab, 'push');
            app.AplicarPre.ButtonPushedFcn = createCallbackFcn(app, @AplicarPreButtonPushed, true);
            app.AplicarPre.Text = 'Aplicar';
            app.TipodeventanaDropDownLabel = uilabel(app.PreprocesamientoTab);
            app.TipodeventanaDropDownLabel.Text = 'Tipo de ventana';
            app.TipoVentana = uidropdown(app.PreprocesamientoTab);
            app.TipoVentana.Items = {'Hamming', 'Hann', 'Kaiser-Bessel', 'Rectangle'};
            app.TipoVentana.ValueChangedFcn = createCallbackFcn(app, @TipoVentanaValueChanged, true);
            app.TipoVentana.Value = 'Hamming';
            app.BetaKaiser = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.BetaKaiser.ValueChangedFcn = createCallbackFcn(app, @BetaKaiserValueChanged, true);
            app.BetaKaiser.Enable = 'off';
            app.BetaKaiser.Value = 0.5;
            app.BetaKaiserLabel = uilabel(app.PreprocesamientoTab);
            app.BetaKaiserLabel.Text = 'Label2';
            app.TamaodelaventanamsLabel = uilabel(app.PreprocesamientoTab);
            app.TamaodelaventanamsLabel.Text = 'Tamaño de la ventana (ms)';
            app.TWin = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.TWin.Limits = [2 Inf];
            app.TWin.ValueChangedFcn = createCallbackFcn(app, @TWinValueChanged, true);
            app.TWin.Value = 25;
            app.VVentana = uibutton(app.PreprocesamientoTab, 'state');
            app.VVentana.ValueChangedFcn = createCallbackFcn(app, @VVentanaValueChanged, true);
            app.VVentana.Enable = 'off';
            app.VVentana.Text = 'Visualizar Ventana';
            app.CorteInfLabel = uilabel(app.PreprocesamientoTab);
            app.CorteInfLabel.Text = 'Corte Inf.';
            app.CorteSupLabel = uilabel(app.PreprocesamientoTab);
            app.CorteSupLabel.Text = 'Corte Sup.';
            app.HzLabel = uilabel(app.PreprocesamientoTab);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.PreprocesamientoTab);
            app.HzLabel_2.Text = 'Hz';
            app.EspectroTab = uitab(app.AjustesTab);
            app.EspectroTab.Title = 'Espectro';
            app.desobreposicinLabel = uilabel(app.EspectroTab);
            app.desobreposicinLabel.Text = '% de sobreposición';
            app.OverKnob = uiknob(app.EspectroTab, 'continuous');
            app.OverKnob.ValueChangedFcn = createCallbackFcn(app, @OverKnobValueChanged, true);
            app.OverKnob.Value = 50;
            app.Demuestrear = uicheckbox(app.EspectroTab);
            app.Demuestrear.ValueChangedFcn = createCallbackFcn(app, @DemuestrearValueChanged, true);
            app.Demuestrear.Text = 'Submuestrear';
            app.GuardarEspButton = uibutton(app.EspectroTab, 'push');
            app.GuardarEspButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarEspButtonPushed, true);
            app.GuardarEspButton.Text = 'Guardar';
            app.Label_2 = uilabel(app.EspectroTab);
            app.Label_2.Text = '';
            app.NFFTDropDownLabel = uilabel(app.EspectroTab);
            app.NFFTDropDownLabel.Text = 'NFFT';
            app.NFFTDropDown = uidropdown(app.EspectroTab);
            app.NFFTDropDown.Items = {'256', '512', '1024'};
            app.NFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @NFFTDropDownValueChanged, true);
            app.NFFTDropDown.Value = '1024';
            app.EspectrogramaLabel = uilabel(app.EspectroTab);
            app.EspectrogramaLabel.Text = 'Espectrograma';
            app.BancoTab = uitab(app.AjustesTab);
            app.BancoTab.Title = 'Banco';
            app.NmerofiltrosSpinnerLabel = uilabel(app.BancoTab);
            app.NmerofiltrosSpinnerLabel.Text = 'Número filtros';
            app.NMelFilter = uispinner(app.BancoTab);
            app.NMelFilter.ValueChangedFcn = createCallbackFcn(app, @NMelFilterValueChanged, true);
            app.NMelFilter.Value = 40;
            app.LimiteinferiorEditFieldLabel = uilabel(app.BancoTab);
            app.LimiteinferiorEditFieldLabel.Text = 'Limite inferior';
            app.LimiteInferior = uieditfield(app.BancoTab, 'numeric');
            app.LimiteInferior.Limits = [0 Inf];
            app.LimiteInferior.ValueChangedFcn = createCallbackFcn(app, @LimiteInferiorValueChanged, true);
            app.LimiteInferior.Value = 300;
            app.LimitesuperiorEditFieldLabel = uilabel(app.BancoTab);
            app.LimitesuperiorEditFieldLabel.Text = 'Limite superior';
            app.LimiteSuperior = uieditfield(app.BancoTab, 'numeric');
            app.LimiteSuperior.Limits = [0 Inf];
            app.LimiteSuperior.ValueChangedFcn = createCallbackFcn(app, @LimiteSuperiorValueChanged, true);
            app.LimiteSuperior.Value = 3000;
            app.MelBandwidth = uispinner(app.BancoTab);
            app.MelBandwidth.ValueChangedFcn = createCallbackFcn(app, @MelBandwidthValueChanged, true);
            app.MelBandwidth.Enable = 'off';
            app.MelBandwidth.Value = 110;
            app.ParametroFB = uiswitch(app.BancoTab, 'slider');
            app.ParametroFB.Items = {'nBands', 'Bandwidth'};
            app.ParametroFB.ValueChangedFcn = createCallbackFcn(app, @ParametroFBValueChanged, true);
            app.ParametroFB.Value = 'nBands';
            app.GenFBButton = uibutton(app.BancoTab, 'push');
            app.GenFBButton.ButtonPushedFcn = createCallbackFcn(app, @GenFBButtonPushed, true);
            app.GenFBButton.Text = 'Generar banco de filtros';
            app.BancodeFiltrosLabel = uilabel(app.BancoTab);
            app.BancodeFiltrosLabel.Text = 'Banco de Filtros';
            app.NormalizarButton = uibutton(app.BancoTab, 'push');
            app.NormalizarButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizarButtonPushed, true);
            app.NormalizarButton.Enable = 'off';
            app.NormalizarButton.Text = 'Normalizar';
            app.EscalaDropDownLabel = uilabel(app.BancoTab);
            app.EscalaDropDownLabel.Text = 'Escala';
            app.EscalaDropDown = uidropdown(app.BancoTab);
            app.EscalaDropDown.Items = {'Mel', 'Bark'};
            app.EscalaDropDown.ValueChangedFcn = createCallbackFcn(app, @EscalaDropDownValueChanged, true);
            app.EscalaDropDown.Value = 'Mel';
            app.EspectroenescalaButton = uibutton(app.BancoTab, 'state');
            app.EspectroenescalaButton.ValueChangedFcn = createCallbackFcn(app, @EspectroenescalaButtonValueChanged, true);
            app.EspectroenescalaButton.Enable = 'off';
            app.EspectroenescalaButton.Text = 'Espectro en escala';
            app.Label_3 = uilabel(app.BancoTab);
            app.Label_3.Text = '';
            app.MFCCTab = uitab(app.AjustesTab);
            app.MFCCTab.Title = 'MFCC';
            app.CoeficientesMFCCEditFieldLabel = uilabel(app.MFCCTab);
            app.CoeficientesMFCCEditFieldLabel.Text = '# Coeficientes MFCC';
            app.nMFCC = uieditfield(app.MFCCTab, 'numeric');
            app.nMFCC.Limits = [0 Inf];
            app.nMFCC.ValueChangedFcn = createCallbackFcn(app, @nMFCCValueChanged, true);
            app.nMFCC.Value = 13;
            app.IncluirLogE = uicheckbox(app.MFCCTab);
            app.IncluirLogE.ValueChangedFcn = createCallbackFcn(app, @IncluirLogEValueChanged, true);
            app.IncluirLogE.Text = 'Incluir Log E';
            app.IncluirDeltas2do = uicheckbox(app.MFCCTab);
            app.IncluirDeltas2do.ValueChangedFcn = createCallbackFcn(app, @IncluirDeltas2doValueChanged, true);
            app.IncluirDeltas2do.Text = 'Incluir Deltas de 2° orden';
            app.IncluirDeltas = uicheckbox(app.MFCCTab);
            app.IncluirDeltas.ValueChangedFcn = createCallbackFcn(app, @IncluirDeltasValueChanged, true);
            app.IncluirDeltas.Text = 'Incluir Deltas';
            app.ProcesarButton = uibutton(app.MFCCTab, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar base';
            app.MostrarDeltaButton = uibutton(app.MFCCTab, 'state');
            app.MostrarDeltaButton.ValueChangedFcn = createCallbackFcn(app, @MostrarDeltaButtonValueChanged, true);
            app.MostrarDeltaButton.Enable = 'off';
            app.MostrarDeltaButton.Text = 'Mostrar Delta';
            app.MostrarDeltaDeltaButton = uibutton(app.MFCCTab, 'state');
            app.MostrarDeltaDeltaButton.ValueChangedFcn = createCallbackFcn(app, @MostrarDeltaDeltaButtonValueChanged, true);
            app.MostrarDeltaDeltaButton.Enable = 'off';
            app.MostrarDeltaDeltaButton.Text = 'Mostrar DeltaDelta';
            app.MostrarEspectroButton = uibutton(app.MFCCTab, 'state');
            app.MostrarEspectroButton.ValueChangedFcn = createCallbackFcn(app, @MostrarEspectroButtonValueChanged, true);
            app.MostrarEspectroButton.Text = 'Mostrar Espectro';
            app.CoeficientesCepstralesenEscalaMelLabel = uilabel(app.MFCCTab);
            app.CoeficientesCepstralesenEscalaMelLabel.Text = 'Coeficientes Cepstrales en Escala Mel';
            app.ReconocedorTab = uitab(app.TabGroup);
            app.ReconocedorTab.Title = 'Reconocedor';
            app.UIAxes3 = uiaxes(app.ReconocedorTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ActivarSwitchLabel = uilabel(app.ReconocedorTab);
            app.ActivarSwitchLabel.Text = 'Activar';
            app.ActivarSwitch = uiswitch(app.ReconocedorTab, 'rocker');
            app.ActivarSwitch.ValueChangedFcn = createCallbackFcn(app, @ActivarSwitchValueChanged, true);
            app.Lamp2 = uilamp(app.ReconocedorTab);
            app.RedLabel = uilabel(app.ReconocedorTab);
            app.RedLabel.Text = 'Red';
            app.SelecTest = uidropdown(app.ReconocedorTab);
            app.SelecTest.Items = {'Primera', 'Animales', 'Palabras con F'};
            app.SelecTest.ItemsData = {'1', '2', '3'};
            app.SelecTest.Value = '1';
            app.RedNeuronalSwitchLabel = uilabel(app.ReconocedorTab);
            app.RedNeuronalSwitchLabel.Text = 'Red Neuronal';
            app.RedNeuronalSwitch = uiswitch(app.ReconocedorTab, 'slider');
            app.RedNeuronalSwitch.Items = {'Test', 'TT'};
            app.RedNeuronalSwitch.ValueChangedFcn = createCallbackFcn(app, @RedNeuronalSwitchValueChanged, true);
            app.RedNeuronalSwitch.Value = 'Test';
            app.VoluntarioLabel_2 = uilabel(app.ReconocedorTab);
            app.VoluntarioLabel_2.Enable = 'off';
            app.VoluntarioLabel_2.Visible = 'off';
            app.VoluntarioLabel_2.Text = 'Datos del Voluntario';
            app.NombreEditFieldLabel_2 = uilabel(app.ReconocedorTab);
            app.NombreEditFieldLabel_2.Enable = 'off';
            app.NombreEditFieldLabel_2.Visible = 'off';
            app.NombreEditFieldLabel_2.Text = 'Nombre';
            app.Nombre_2 = uieditfield(app.ReconocedorTab, 'text');
            app.Nombre_2.Enable = 'off';
            app.Nombre_2.Visible = 'off';
            app.EdadEditFieldLabel_2 = uilabel(app.ReconocedorTab);
            app.EdadEditFieldLabel_2.Enable = 'off';
            app.EdadEditFieldLabel_2.Visible = 'off';
            app.EdadEditFieldLabel_2.Text = 'Edad';
            app.Edad_2 = uieditfield(app.ReconocedorTab, 'numeric');
            app.Edad_2.Limits = [0 35];
            app.Edad_2.ValueDisplayFormat = '%.0f';
            app.Edad_2.Enable = 'off';
            app.Edad_2.Visible = 'off';
            app.Edad_2.Value = 18;
            app.SexoDropDownLabel_2 = uilabel(app.ReconocedorTab);
            app.SexoDropDownLabel_2.Enable = 'off';
            app.SexoDropDownLabel_2.Visible = 'off';
            app.SexoDropDownLabel_2.Text = 'Sexo';
            app.Sexo_2 = uidropdown(app.ReconocedorTab);
            app.Sexo_2.Items = {'Mujer', 'Hombre'};
            app.Sexo_2.Enable = 'off';
            app.Sexo_2.Visible = 'off';
            app.Sexo_2.Value = 'Hombre';
            app.EscolaridadDropDownLabel_2 = uilabel(app.ReconocedorTab);
            app.EscolaridadDropDownLabel_2.Enable = 'off';
            app.EscolaridadDropDownLabel_2.Visible = 'off';
            app.EscolaridadDropDownLabel_2.Text = 'Escolaridad';
            app.Escolaridad_2 = uidropdown(app.ReconocedorTab);
            app.Escolaridad_2.Items = {'Preparatoria', 'Licenciatura', 'Posgrado'};
            app.Escolaridad_2.Enable = 'off';
            app.Escolaridad_2.Visible = 'off';
            app.Escolaridad_2.Value = 'Licenciatura';
            app.GuardarResultados = uibutton(app.ReconocedorTab, 'push');
            app.GuardarResultados.Enable = 'off';
            app.GuardarResultados.Visible = 'off';
            app.GuardarResultados.Text = 'Guardar';
            app.VoluntarioLabel_3 = uilabel(app.ReconocedorTab);
            app.VoluntarioLabel_3.Enable = 'off';
            app.VoluntarioLabel_3.Visible = 'off';
            app.VoluntarioLabel_3.Text = 'Resultados';
            app.MostrarButton = uibutton(app.ReconocedorTab, 'state');
            app.MostrarButton.Enable = 'off';
            app.MostrarButton.Visible = 'off';
            app.MostrarButton.Text = 'Mostrar';
            app.PruebaLabel = uilabel(app.ReconocedorTab);
            app.PruebaLabel.Enable = 'off';
            app.PruebaLabel.Text = 'Prueba';
            app.SelecPrueba = uidropdown(app.ReconocedorTab);
            app.SelecPrueba.Items = {'Memoria Verbal', 'Repetición', 'Fluidez Verbal'};
            app.SelecPrueba.ItemsData = {'1', '2', '3'};
            app.SelecPrueba.Enable = 'off';
            app.SelecPrueba.Value = '1';
            app.Mensaje = uilabel(app.UIFigure);
            app.Mensaje.Text = '';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = 'Apagado';
            app.Palabra = uilabel(app.UIFigure);
            app.Palabra.Text = '';
            app.ApagarSwitchLabel = uilabel(app.UIFigure);
            app.ApagarSwitchLabel.Text = 'Apagar';
            app.Apagar = uiswitch(app.UIFigure, 'toggle');
            app.Apagar.ValueChangedFcn = createCallbackFcn(app, @ApagarValueChanged, true);
            app.Apagar.Value = 'On';
            app.GrabarLabel = uilabel(app.UIFigure);
            app.GrabarLabel.Text = 'Grabar';
            app.Grabar = uiswitch(app.UIFigure, 'slider');
            app.Grabar.ValueChangedFcn = createCallbackFcn(app, @GrabarValueChanged, true);
            app.Grabar.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TT2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end