classdef adquisicion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Reproducir                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Lamp                            matlab.ui.control.Lamp
        Guardar                         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AdquisicinTab                   matlab.ui.container.Tab
        FrecuenciademuestreoDropDownLabel  matlab.ui.control.Label
        fMuestreo                       matlab.ui.control.DropDown
        NombreEditFieldLabel            matlab.ui.control.Label
        Nombre                          matlab.ui.control.EditField
        EdadEditFieldLabel              matlab.ui.control.Label
        Edad                            matlab.ui.control.NumericEditField
        DuracinsegLabel                 matlab.ui.control.Label
        Duracion                        matlab.ui.control.NumericEditField
        ResolucinDropDownLabel          matlab.ui.control.Label
        Resolucion                      matlab.ui.control.DropDown
        DirSelect                       matlab.ui.control.Button
        Ready2Rec                       matlab.ui.control.CheckBox
        SexoDropDownLabel               matlab.ui.control.Label
        Sexo                            matlab.ui.control.DropDown
        VoluntarioLabel                 matlab.ui.control.Label
        EscolaridadDropDownLabel        matlab.ui.control.Label
        Escolaridad                     matlab.ui.control.DropDown
        AudioLabel                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PatronesTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        AjustesTab                      matlab.ui.container.TabGroup
        PreprocesamientoTab             matlab.ui.container.Tab
        FrecuenciasdecorteparafiltropasabandasLabel  matlab.ui.control.Label
        FCorteL                         matlab.ui.control.NumericEditField
        FCorteH                         matlab.ui.control.NumericEditField
        Bode                            matlab.ui.control.StateButton
        AplicarPre                      matlab.ui.control.Button
        TipodeventanaDropDownLabel      matlab.ui.control.Label
        TipoVentana                     matlab.ui.control.DropDown
        BetaKaiser                      matlab.ui.control.NumericEditField
        BetaKaiserLabel                 matlab.ui.control.Label
        VVentanaButton                  matlab.ui.control.Button
        EspectroTab                     matlab.ui.container.Tab
        desobreposicinLabel             matlab.ui.control.Label
        OverKnob                        matlab.ui.control.Knob
        TamaodelaventanamsLabel         matlab.ui.control.Label
        TWin                            matlab.ui.control.NumericEditField
        AplicarEspectro                 matlab.ui.control.Button
        Demuestrear                     matlab.ui.control.CheckBox
        GuardarEspButton                matlab.ui.control.Button
        MelTab                          matlab.ui.container.Tab
        NmerofiltrosSpinnerLabel        matlab.ui.control.Label
        NMelFilter                      matlab.ui.control.Spinner
        LimiteinferiorEditFieldLabel    matlab.ui.control.Label
        LimiteInferior                  matlab.ui.control.NumericEditField
        LimitesuperiorEditFieldLabel    matlab.ui.control.Label
        LimiteSuperior                  matlab.ui.control.NumericEditField
        AnchodebandamelsSpinnerLabel    matlab.ui.control.Label
        MelBandwidth                    matlab.ui.control.Spinner
        ParametroFB                     matlab.ui.control.Switch
        GenFBButton                     matlab.ui.control.Button
        NormalizarporDropDownLabel      matlab.ui.control.Label
        Normalizar                      matlab.ui.control.DropDown
        RangodelbancodefiltrosMelLabel  matlab.ui.control.Label
        MFCCTab                         matlab.ui.container.Tab
        CoeficientesMFCCEditFieldLabel  matlab.ui.control.Label
        nMFCC                           matlab.ui.control.NumericEditField
        IncluirLogE                     matlab.ui.control.CheckBox
        IncluirDeltas2do                matlab.ui.control.CheckBox
        GenMFCCButton                   matlab.ui.control.Button
        IncluirDeltas                   matlab.ui.control.CheckBox
        EspectroButton                  matlab.ui.control.Button
        FiltroButton                    matlab.ui.control.Button
        MelButton                       matlab.ui.control.Button
        MFCCButton                      matlab.ui.control.Button
        ProcesarButton                  matlab.ui.control.Button
        Mensaje                         matlab.ui.control.Label
        Status                          matlab.ui.control.Label
        Palabra                         matlab.ui.control.Label
        ApagarSwitchLabel               matlab.ui.control.Label
        Apagar                          matlab.ui.control.ToggleSwitch
        GrabarLabel                     matlab.ui.control.Label
        Grabar                          matlab.ui.control.Switch
    end

%{ 
  El programa comienza con todos los campos desactivados salvo los de información del
  voluntario y la configuración de la grabación: depues de que la función startup
  inicializa algunas variables y una vez que el usuario introduce la información requerida
  este selecciona la checkbox ready2Rec, la cual habilita los controles de grabación
  para luego llamar a la función Iniciar(app) la cual hace las comprobaciones necesarias para el
  control de los archivos de audio.
  Las funciones Grabar, Reproducir y Guardar controlan la adquisición de la señal de audio,
  Guardar genera el archivo .wav y agrega el registro de esta grabación al archivo de control del
  voluntario; Apagar actualiza el archivo main.csv donde se encuentra el registro de los voluntarios
  grabados.
  Las funciones de la tab Patrones son para la configuración de la extracción de características y
  trabajan con la última palabra guardada en la memoria, su función es obtener los parámetros con
  los que se extraerán los patrones al resto de la base de datos
  %}
    
    
    properties (Access = private)
        % FALTA DEFINIR COMO SE VA A SOLICITAR LA INCLUSIÓN DE LA ENERGÍA LOG
        
        % Variables de Adquisición
        audio       % Objeto clase audiorecorder usado para la adquisición de audio
        signal      % Vector numérico conteniendo la señal de audio adquirida
        dirname     % Directorio general de la base de datos
        main        % Tabla con el registro general de los voluntarios que se han grabado
        volun       % Tabla con el registro de palabras grabadas y procesadas por el voluntario de turno
        I           % Índice para localizar a la ultima palabra grabada por una persona
        VCeros      % Vector de ceros de la longitud de la lista de palabras "Diccionario"
        VLogical    % Vector de ceros lógico con longitud de la lista de palabras
        mainfile    % Ruta al .csv principal de la base de datos
        volfile     % Ruta al .csv del voluntario
        voldir      % Directorio del voluntario
      
        % Variables de Preprocesamiento
        RecordedFs  % Frecuencia de muestreo de un audio previamente grabado
        Cutoff      % Frecuencias de cortes
        filtrada    % Señal filtrada
        filtro      % Polinomio del filtro pasa banda
        wavfile     % Ruta del ultimo archivo de audio grabado
        t           % Vector con el tiempo de la señal
        Dsample     % Señal demuestrada
        Dfs         % Frecuencia de muestreo de la señal demuestreada
        
        % Variables de Segmentación (Framing)
        WinLength   % Ancho de la ventana en muestras
        Overlap     % Sobreposición en muestras
        HopLength   % Número de muestras de avance
        numHops     % Número de frames
        pWindow     % Matriz de ventanas para reducir tiempo de cómputo
        coloffsets  % Vector de avance por cada segmento dado en índices
        
        % Variables de espectrograma
        Tespectro   % Vector de tiempo para espectro
        
        % Variables para extracción de características
        Framedfs    % Frecuencia de muestreo después de incluir o no demuestrado
        Range       % Rango de frecuencias del banco de filtros mel
        FBBandwidth % Ancho de banda de cada filtro del banco de filtros mel
        NFB         % Número de filtros en el banco de filtros mel
        Mejes       %  del banco de filtros en Mels
        Fejes       %  del banco de filtros en Hertz
        handle      % Matriz que contiene los handler de las gráficas del diseño del filtro mel
        FilterBank  % Matriz con el banco de filtros mel
        logE        % Logaritmo de la energía de la señal
        DCTmatrix   % Matriz para aplicar la Transformada Discreta de Coseno a la señal
        FirstTime   % Bandera usada en el cálculo de Deltas
        
        % Objetos generados
        Win         % Ventana
        pasabanda   % Objeto tipo digitalFilter con la información del filtro pasabandas
        Framed      % Señal ventaneada y segmentada
        Sxx         % Parte real de la FFT de la señal segmentada
        nFilterBank % Banco de filtros normalizado
        MelSpect    % FFT de la señal en escala mel
        coeffs      % Coeficientes cepstrales en escala mel
        Delta       % Cambio entre los MFCC
        Delta2      % Cambro entre Deltas

    end
% Anotar los valores por default de los campos de entrada de datos de la GUI
    properties (Constant)
        varname = {'ID' 'Edad' 'Sexo' 'Escolaridad'};
        volname = {'LastWord' 'Diccionario' 'Registro' 'Procesado'}
        mainfilename = 'main.csv'; % Registro de palabras por persona
        Diccionario = {'Gato'; 'Pera'; 'Mano'; 'Fresa'; 'Vaca'; 'Codo'; 'Sol';...
                       'Ventana'; 'El'; 'Niño'; 'Llora'; 'Hombre'; 'Camina';...
                       'Lentamente'; 'Por'; 'La'; 'Calle'; 'Leon'; 'Caballo';...
                       'Toro'; 'Perro'; 'Cabra'; 'Pajaro'; 'Pollo'; 'Cerdo';...
                       'Vaca'; 'Rata'; 'Cordero'; 'Gallo'; 'Raton'; 'Mono';...
                       'Pez'; 'Gallina'; 'Serpiente'; 'Tigre'; 'Oso'; 'Canario';...
                       'Fue'; 'Forma'; 'Fueron'; 'Frente'; 'Fin'; 'Fuera';...
                       'Final'; 'Familia'; 'Falta'; 'Fuerza'};
    end

    methods (Access = private)  
  
        function Iniciar(app)
            app.mainfile = fullfile(app.dirname, app.mainfilename);
            app.voldir = fullfile(app.dirname, app.Nombre.Value);
            app.volfile = fullfile(app.voldir, strcat(app.Nombre.Value, '.csv'));
            ID = 1;
            if exist(app.mainfile, 'file') 
            % Existe main.csv, no es la primera grabacion
                app.main = readtable(app.mainfile, 'ReadRowNames', true);
                if ismember(app.Nombre.Value, app.main.Properties.RowNames)
                % El voluntario ha sido registrado previamente
                    if exist(app.volfile, 'file')
                        app.volun = readtable(app.volfile);
                    else
                        text = sprintf('%s\n%s','Algo anda mal: el voluntario está registrado pero no',...
                                       'existe su archivo');
                        uialert(app.UIFigure, text', 'Error');
                    end
                else
                % Es un voluntario nuevo, no está en main.csv
                    temp = table(ID, app.Edad.Value, {app.Sexo.Value},... 
                                 {app.Escolaridad.Value}, 'VariableNames',...
                                 app.varname, 'RowNames', {app.Nombre.Value});
                    app.volun = table(app.VLogical, app.Diccionario, app.VCeros,...
                                      app.VLogical, 'VariableNames', app.volname);
                    app.main = [app.main; temp];
                end
            else
            % No existe main.csv, es la primera grabación en esa base de datos
                app.main = table(ID, app.Edad.Value, {app.Sexo.Value},... 
                                 {app.Escolaridad.Value}, 'VariableNames',...
                                 app.varname, 'RowNames', {app.Nombre.Value});
                mkdir(app.voldir);
                app.volun = table(app.VLogical, app.Diccionario, app.VCeros,...
                                  app.VLogical, 'VariableNames', app.volname);
            end
        end
    
        function filter(app, dir)
            cla(app.UIAxes, 'reset'); cla(app.UIAxes2, 'reset');
            [x,Fs] = audioread(dir);
            app.RecordedFs = Fs;
            app.Cutoff(1) = app.FCorteL.Value;
            app.Cutoff(2) = app.FCorteH.Value;            
            [app.filtrada, app.filtro] = bandpass(x, app.cutoff, app.RecordedFs);
            app.t = (0:length(x)-1)/app.RecordedFs;
            plot(app.UIAxes, app.t, [x, app.filtrada]);
            xlabel(app.UIAxes, 'Tiempo (s)');  
            legend(app.UIAxes, 'Original', 'Filtradad')
            title(app.UIAxes, 'Señal Filtrada vs Señal Original')
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            [P,F] = pspectrum([x, app.filtrada], app.RecordedFs, 'Leakage', 0.9);    
            plot(app.UIAxes2, F, 10*log10(P))
            axis(app.UIAxes2, 'tight')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)');
            ylabel(app.UIAxes2, 'Potencia espectral (dB)');
            oracion = "Filtro pasabadas con cortes a: %d y %d Hz";
            title(app.UIAxes2, sprintf(oracion, app.cutoff(1), app.cutoff(2)))    
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
        end
    
        function filterBankParameters (app)
            delete(app.handle)
            app.Range = [app.LimiteInferior.Value app.LimiteSuperior.Value];
            range = 2595.*log10(1+app.Range./700); % De hertz a mels
            if strcmpi(app.ParametroFB.Value,'Bandwidth')
                app.FbBandwidth = app.MelBandwidth.Value;
                avance = app.FBBandwidth/2;
                app.Mejes = range(1): avance: range(2);
                N = length(app.Mejes);
                app.NFB = N-2;
            else
                app.NFB = app.NMelFilter.Value;
                N = app.NFB + 2;
                app.Mejes = linspace(range(1), range(2), N);
                app.FBBandwidth = app.Mejes(3) - app.Mejes(1);
            end
            app.handle = zeros(N,3);
            app.Fejes = 700.*((10.^(app.Mejes/2595))-1); 
            for i = 1:N
                app.handle(i,1) = plot(app.UIaxes, [0, app.Fejes(i)], [app.(i), app.(i)], 'r-.');
                app.handle(i,2) = plot(app.UIaxes, [app.Fejes(i), app.Fejes(i)], [0, app.(i)], 'r-.');
                app.handle(i,3) = plot(app.UIaxes, app.Fejes(i), app.Mejes(i), 'Marker','o', 'MarkerEdgeColor','m');
            end                  
        end
    
        function segmentacion(app, x, freq)
            % Se cargan los valores de la sementación: largo del segmento (igual al largo de la ventana),
            % número de muestras que se sobrepone la cada segmento de la señal y el tipo de ventana
            app.WinLength = round(freq*(app.TWin.Value/1000)); % # muestras para la ventana seleccionada
            app.Overlap = round(app.WinLength*(app.OverKnob.Value/100)); % # muestras de sobreposición de la ventana
            app.HopLength = app.WinLength - app.Overlap;
            nRow = length(x);
            % Número de segementos para la señal con sobreposición y ventana especificada
            app.numHops = floor((nRow-app.WinLength)/app.HopLength) + 1;
            app.Ventana(app) % Genera función ventana
            % Repite la ventana una vez por cada segmento 
            app.pWindow = repmat(app.WinLength, 1, app.numHops);
            % Se segmenta la señal por medio de la creación de una matriz de índices "Idx"
            % sobre la cual se reacomoda la señal elemento a índice de la siguiente forma x(Idx);
            ncol = fix((nRow-app.Overlap)/(app.HopLength)); % # columnas
            % Vector con los avances de índice de cada columna, ej.: para HopLength = 110; y ncol = 97
            % coloffsets = [0 110 220 330 ... 10560]
            app.coloffsets = (0:(ncol-1))*(app.HopLength);
            % Para HopLength = 110 y WinLength = 331, nRow = 11025 con el vector coloffsets anterior
            % Idx = [1, 111, 221, ... 10561; 2, 112, 222, ..., 10562; ...; 331, 441, 551, ... 10891];
            % El restante de la señal se trunca, en este caso 133 muestras de 10892 a 11025
            Idx = (1:app.WinLength)'+app.coloffsets;
            % Se segmenta la señal de acuerdo a los índices Idx y se le aplica la ventana a cada segmento
            app.Framed = x(Idx)*app.pWindow;    
        end
        
        function Espectro(app, x, fs, axes, titulo)
            cla(axes, 'reset');
            nfft = app.WinLength;
            app.Tespectro = (app.coloffsets+(nfft/2)')/fs;            
            app.Sxx = abs(fft(x,nfft));
            f = psdfreqvec('npts',nfft,'Fs',fs,'Range','half');
            [Pxx,f] = computepsd(app.Sxx, f, 'onesided', nfft, fs);
            [f,~,uf] = engunits(f,'unicode');
            freqlbl = getfreqlbl([uf 'Hz']);
            [app.Tespectro,~,ut] = engunits(app.Tespectro,'unicode','time');
            timelbl = [getString(message('signal:spectrogram:Time')) ' (' ut ')'];
            imagesc(axes, app.Tespectro, f, 10*log10(abs(Pxx)+eps))
            xlbl = timelbl;
            ylbl = freqlbl;
            axes.YDir = 'normal';
            h = colorbar;
            cblabel = getString(message('signal:dspdata:dspdata:PowerfrequencydBHz'));
            h.Label.String = cblabel;
            ylabel(ylbl);
            xlabel(xlbl);                    
            axes.PlotBoxAspectRatioMode = 'manual';
            axes.PlotBoxAspectRatio(1) = .95;
            axis(axes, 'tight')    
            title(axes, titulo)   
        end
        
        function Ventana(app, value)
             switch value
                case 'Hamming'
                    app.Win = hamming(app.WinLength);
                    app.BetaKaiser.Enable = 'off';
                case 'Hann'
                    app.Win = hann(app.WinLength);
                    app.BetaKaiser.Enable = 'off';
                case 'Kaiser-Bessel'
                    app.Win = kaiser(app.WinLength, app.BetaKaiser.Value);
                    app.BetaKaiser.Enable = 'on';
                case 'Rectangle'
                    app.Win = rectwin(app.WinLength);
                    app.BetaKaiser.Enable = 'off';
            end
        end
        
        function MelFilterBank(app)
            cla(app.UIAxes2, 'reset');
            app.filterBank = zeros(app.WinLength, app.NFB);
            linFq = (0:app.WinLength-1)/app.WinLength*app.Framedfs;
            N = length(app.Fejes);
            % Determine inflection points
            p = zeros(N, 1);
            for edgeNumber = 1:N
                p(edgeNumber) = find(linFq > app.Fejes(edgeNumber), 1, 'first');
            end  
            % Create triangular filters for each band
            bw = diff(app.Fejes);
            for k = 1:app.NFB
            % Rising side of triangle
                app.FilterBank(p(k):p(k+1)-1,k) = (linFq(p(k):p(k+1)-1) - app.Fejes(k))...
                            / bw(k);    
                % Falling side of triangle
                app.FilterBank(p(k+1):p(k+2)-1,k) = (app.Fejes(k+2) - linFq(p(k+1):...
                           p(k+2)-1)) / bw(k+1);
            end
            numBins = length(app.FilterBank);                
            binIndex = 1:numBins;
            binIncrement = app.Framedfs/numBins;
            freq = binIndex*binIncrement;
            plot(app. UIAxes2, freq, app.FilterBank)
            axis([0 app.Framedfs/2 0 1])
        end
        
        function FBNormalization(app)
            cla(app.UIAxes, 'reset');
            if strcmpi (app.Normalizar.Value, 'Area')
            weightPerBand = sum(app.FilterBank.^2);
            app.nFilterBank = zeros(size(app.FilterBank));
            for i = 1:app.NFB
                app.nFilterBank(:,i) = app.FilterBank(:,i)./weightPerBand(i);
            end
            titulo = 'Normalizado por Área';
            elseif strcmpi (app.Normalizar.Value, 'Ancho de Banda')
                weightPerBand = 2./app.FbBandwidth;
                for i = 1:numBands
                    app.nfilterBank(:,i) = app.FilterBank(:,i).*weightPerBand(i);
                end
            titulo = 'Normalizado por Ancho de Banda';
            end
            plot(app.UIAxes2, freq, app.nFilterBank)
            axis([0 app.Framedfs/2 0 inf])
            title(app.UIAxes, titulo)        
        end
        
        function ApplyFB(app)
            cla(app.UIAxes2, 'reset')
            app.MelSpect = app.nFilterBank'*appSxx + sqrt(eps('double'));
            imagesc(app.UIAxes2, app.Tespectro, app.Mejes, 10*log10(abs(app.MelSpect)+eps));
            app.UIAxes2.YDir = 'normal';
            title(app.UIAxes, 'Espectrograma en escala mel')           
        end
        
        function MFCC(app)
            cla(app.UIAxes, 'reset')
            app.DCT(app);
            temp = app.DCTmatrix * app.MelSpect;
            if app.IncluirLogE.Value               
                app.coeffs = [app.logE; temp];
            else                                  
                app.coeffs = temp;
            end
            if app.IncluirDeltas.Value
                app.Delta = app.CompDelta(app, app.coeffs);
                if app.IncluirDeltas.Value
                    app.Delta2 = app.CompDelta(app, app.Delta);
                end
            end
            imagesc(app.UIAxes, app.Tespectro, app.Mejes, log10(abs(app.coeffs)));
            app.UIAxes.YDir = 'normal';
            title(app.UIAxes, 'MFCCs')  
        end
        
        function DCT(app)
        % Calcula la matriz requerida para aplicar la Transformada Discreta de
        % Coseno 2 unitaria, de acuerdo con las ecuaciones 8.158, 8.159 y 8.160
        % del libro "Discrete Time Signal Processing" de Oppenheim
            N = app.nMFCC.Value;
            K = app.NFB;
            matrix = zeros(N,K);
            A = sqrt(1/K);
            B = sqrt(2/K);
            C = 2*K;
            for n = 1:N
                for k = 1:K
                    if n == 1
                        matrix(n,k) = A*cos(pi*(n-1)*(2*k-1)/C);
                    else
                        matrix(n,k) = B*cos(pi*(n-1)*(2*k-1)/C);
                    end
                end
            end
            app.DCTmatrix = matrix;
        end
        
        function y = CompDelta(~, x)
            % Se fija a 2 la ventana de los elementos involucrados en el cálculo de
            % las diferencias Delta, una mayor ventana implica realizar la diferenciación
            % por medio de un filtro en el dominio cepstral, no he revisado los detalles 
            % del mismo, ni si distorsiona la señal; si se desea ampliar la ventana > 2 debe ser impar       
            M = 1; % instrucción original; floor(DeltaLength/2); con DeltaLength >= 2
            xPadded = cat(1, x(1,:), x, x(end,:));
            temp = diff(xPadded, 1);
            y = temp(M:end-M, :);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.TabGroup.SelectedTab = app.AdquisicinTab;
            app.VCeros = zeros(length(app.Diccionario), 1);
            app.VLogical = logical(app.VCeros);
            text = sprintf('%s\n%s','Ingresa los datos del voluntario así como la carpeta',...
                           'donde se guardarán las grabaciones, luego presiona "Listo"');
            app.Mensaje.Text = (text);
            text = strcat('Factor',' ', char(946));
            app.BetaKaiserLabel.Text = text;
            app.AjustesTab.Visible = 'off';
            app.h = zeros(N, 3)';
        end

        % Value changed function: Apagar
        function ApagarValueChanged(app, event)
            salir = uiconfirm(app.UIFigure,'¿Realmente quieres salir?',...
                              'Confirmar Salida', 'Icon','warning');
            if strcmpi(salir,'ok')
                writetable(app.main, app.mainfile, 'WriteRowNames',true);
                delete(app);
            end
        end

        % Button pushed function: DirSelect
        function DirSelectButtonPushed(app, event)
            app.dirname = uigetdir('C:\');
            % Existe un bug que ocasiona que después de usar uigetdir no se regrese a la
            % pantalla de la aplicación, el encender y apagar la propiedad de visibilidad
            % de toda la aplicación es un parche a este problema
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: Grabar
        function GrabarValueChanged(app, event)
            if strcmpi(app.Grabar.Value,'on')
                app.I = find(app.volun.LastWord);
                if app.I == length(app.Diccionario)
                    app.I = 1;
                elseif isempty(app.I)
                    app.I = 1;
                else
                   app.I = app.I + 1;
                end
                app.Palabra.Text = app.volun.Diccionario(app.I);
                app.Mensaje.Text = 'A la cuenta de 3 pronuncia la siguiente palabra:';
                app.Status.Text = 'Grabando en 3';
                j = 2;
                for i = 0:2
                    app.Lamp.Color = 'yellow';
                    pause(0.4)
                    app.Lamp.Color = [0.9, 0.9, 0.9];
                    pause(0.3)
                    % strcat elimina los espacios a menos que se use sintaxis de 
                    app.Status.Text = strcat('Grabando en: ', {' '}, num2str(j));
                    j = j-1;
                end
                fs = str2double(app.fMuestreo.Value);
                nBits = str2double(app.Resolucion.Value);
                app.audio = audiorecorder(fs,nBits,1);
                app.Lamp.Color = 'red';
                app.Status.Text = 'Grabando';
                recordblocking(app.audio, app.Duracion.Value);
                app.Lamp.Color = [0.9, 0.9, 0.9];
                app.Status.Text = 'Apagado';
                app.Grabar.Value = 'Off';
                app.signal = getaudiodata(app.audio);
                tiempo=linspace(0, app.Duracion.Value, numel(app.signal));
                plot(app.UIAxes, tiempo, app.signal)
                app.Reproducir.Enable = 'on';
                app.Guardar.Enable = 'on';
            end
        end

        % Button pushed function: Guardar
        function GuardarPushed(app, event)
        % temp es la ruta de acceso a la carpeta donde se guarda las grabaciones de la palabra
            temp = string(fullfile(app.dirname, app.Nombre.Value, app.Diccionario(app.I)));
            % isempty no funciona ya que evalúa app.volun.Registro(app.I) como cell con array numérico
            % accesado como app.volun(app.I, 'Registro) tampoco funciona ya que se devuelve un dato
            % tipo tabla, el cual a su vez contiene un cell con array vacío; aparentemente el
            % operador == si evalúa el contenido de la tabla cuando se accesa por notación de puntos
            % sin embargo no está definico en notación de paréntesis
            if app.volun.Registro(app.I) == 0
                mkdir(temp)
            end
            i = app.volun.Registro(app.I) + 1;
            % wavname es el nombre del archivo wav de cada grabacion por palabra: 1.wav, 2.wav, etc
            wavname = strcat(num2str(i), '.wav');
            app.wavfile = fullfile(temp, wavname);
            % Alerta si ocurrió un error al escribir el archivo de audio
            %try
                audiowrite(app.wavfile, app.signal, str2double(app.fMuestreo.Value),...
                           'BitsPerSample', str2double(app.Resolucion.Value));
            %catch
            %    uialert(app.UIFigure, 'Algo salío mal al guardar la grabación',...
            %            'Error al guardar el audio');
            %    return
            %app.volun.Registro(app.I) = app.volun.Registro(app.I) + 1;end
            app.volun.Registro(app.I) = app.volun.Registro(app.I) + 1;
            if app.I == 1
                app.volun.LastWord(length(app.Diccionario)) = false;
                app.volun.LastWord(app.I) = true;
            else
                app.volun.LastWord(app.I-1) = false;
                app.volun.LastWord(app.I) = true;
            end
            app.Reproducir.Enable = 'off';
            app.Guardar.Enable = 'off';
            writetable(app.volun, app.volfile, 'WriteRowNames',true);
            app.Mensaje.Text = '¡Archivo Guardado exitosamente!';
            cla(app.UIAxes);
            pause (1);
            app.Mensaje.Text = 'Listo para continuar grabando';
            app.Palabra.Text = '';
            app.AjustesTab.Visible = 'on';
            app.FiltroButton.Enable = 'on';
        end

        % Button pushed function: Reproducir
        function ReproducirPushed(app, event)
            app.Lamp.Color = 'green';
            app.Status.Text = 'Reproduciendo';
            sonido = audioplayer(app.signal, str2double(app.fMuestreo.Value));
            playblocking(sonido)
            app.Lamp.Color = [0.9, 0.9, 0.9];
            app.Status.Text = 'Apagado';
        end

        % Value changed function: Ready2Rec
        function Ready2RecValueChanged(app, event)
            %{ 
            Recording switch is disable by default, this checkbox could enable it if
            conditions are met: if set-up values are given and checkbox is true,
            switch gets enabled meanwhile config panel gets disabled.
            Checked values are: name and storage directory; if one is empty then 
            throws a msgbox asking for the missing value.
            When checkbox is False, deactivates switch and enables config panel.
            %}
            value = app.Ready2Rec.Value;
            nombre = ~isempty(app.Nombre.Value);
            dir = ~isempty(app.dirname);
            if value && nombre && dir
                app.Grabar.Enable = 'on';
                app.Nombre.Enable = 'off';
                app.fMuestreo.Enable = 'off';
                app.Resolucion.Enable = 'off';
                app.Edad.Enable = 'off';
                app.Duracion.Enable = 'off';
                app.DirSelect.Enable = 'off';
                app.Sexo.Enable = 'off';
                app.Escolaridad.Enable = 'off';
                Iniciar(app);
                text = sprintf('%s\n%s','Acciona el switch izquierdo para comenzar a',...
                           ' grabar después de la cuenta regresiva');
                app.Mensaje.Text = (text);  
            else
                if ~nombre
                    uialert(app.UIFigure, 'Introduce los datos del voluntario',...
                            'Adquisición de voz', 'Icon', 'info');
                end
                if ~dir
                    uialert(app.UIFigure, 'Selecciona la carpeta de la base de datos',...
                            'Adquisición de voz', 'Icon', 'info');
                end
                app.Grabar.Enable = 'off';
                app.Nombre.Enable = 'on';
                app.fMuestreo.Enable = 'on';
                app.Resolucion.Enable = 'on';
                app.Edad.Enable = 'on';
                app.Duracion.Enable = 'on';
                app.DirSelect.Enable = 'on';
                app.Sexo.Enable = 'on';
                app.Escolaridad.Enable = 'on';
                app.Reproducir.Enable = 'off';
                app.Guardar.Enable = 'off';
                app.Ready2Rec.Value = 0;                
            end          
        end

        % Button pushed function: FiltroButton
        function FiltroButtonPushed(app, event)
            app.AjustesTab.SelectedTab = app.PreprocesamientoTab;            
            app.filter(app, app.wavfile)
            app.EspectroButton.Enable = 'on';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            if strcmpi(selectedTab, 'Patrones')
                uialert(app.UIFigure,'Entra aquí sólo si sabes lo que estas haciendo',...
                        'Procesamiento de señales', 'Icon','warning');
            end
        end

        % Button pushed function: EspectroButton
        function EspectroButtonPushed(app, event)
            app.AjustesTab.SelectedTab = app.EspectroTab; 
            app.segmentacion(app, app.signal, app.RecordedFs)
            app.Espectro(app, app.Framed, app.RecordedFs, app.UIAxes, 'Señal Original')
            app.segmentacion(app, app.filtrada, app.RecordedFs)
            app.Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2, 'Señal Filtrada')
            app.MelButton.Enable = 'on';
        end

        % Button pushed function: MelButton
        function MelButtonPushed(app, event)
             cla(app.UIAxes, 'reset'); cla(app.UIAxes2, 'reset');
             app.AjustesTab.SelectedTab = app.MelTab;
             app.LimiteSuperior.Value = app.Framedfs/2;
             app.LimiteSuperior.Limits = [app.Cuttof(1) app.Framedfs/2];
             app.LimiteInferior.Limits = [0 app.Cuttof(2)];
             x = 0:fs/2; y = 2595.*log10(1+x./700);
             [x,~,ux] = engunits(x,'unicode');
             [y,~,uy] = engunits(y,'unicode');
             freqlbl = getfreqlbl([ux 'Hz']);
             mfreqlbl = getfreqlbl([uy 'Mels']);
             plot(app.UIAxes, x, y)
             hold(app.UIAxes);
             axis(app.UIAxes, [0 app.Framedfs/2 0  2595.*log10(1+(app.Framedfs/2)./700)]) 
             title(app.UIAxes, 'Escala Mel');
             xlabel(app.UIAxes, freqlbl);
             ylabel(app.UIAxes, mfreqlbl);
             filterBankParameters(app);
        end

        % Button pushed function: MFCCButton
        function MFCCButtonPushed(app, event)
            app.AjustesTab.SelectedTab = app.MFCCTab;
            app.ApplyFB(app)
            app.FirstTime = True;
            app.ProcesarButton.Enable = 'on';
        end

        % Value changed function: Bode
        function BodeValueChanged(app, event)
            value = app.Bode.Value;
            cla(app.UIAxes, 'reset');
            if value
                [H,F] = freqz(app.filtro, 512, app.RecordedFs);
                yyaxis (app.UIAxes, 'right')
                plot(app.UIAxes, F, 10*log10(abs(H(1:512))));
                xlabel(app.UIAxes,'Frecuencia (Hz)');
                ylabel(app.UIAxes,'Magnitud (dB)');
                yyaxis (app.UIAxes, 'left')
                [PHI, F] = phasez(app.filtro,512,11025);
                plot(app.UIAxes, F, PHI)
                ylabel(app.UIAxes, 'Fase (rad)');
                app.Bode.Text = 'Mostrar señal filtrada';
            else
                % t = (0:length(app.signal)-1)/app.RecordedFs;
                plot(app.UIAxes, app.t, [app.signal, app.filtrada]);
                xlabel(app.UIAxes, 'Tiempo (s)');  
                legend(app.UIAxes, 'Original', 'Filtradad')
                title(app.UIAxes, 'Señal Filtrada vs Señal Original')
                app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            end
        end

        % Value changed function: TipoVentana
        function TipoVentanaValueChanged(app, event)
            value = app.TipoVentana.Value;
            app.Ventana(app, value);
        end

        % Value changed function: BetaKaiser
        function BetaKaiserValueChanged(app, event)
            app.Ventana(app, value);
        end

        % Button pushed function: AplicarEspectro
        function AplicarEspectroButtonPushed(app, event)
            app.segmentacion(app, app.filtrada, app.RecordedFs)
            app.Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2, 'Señal Filtrada')
        end

        % Button pushed function: AplicarPre
        function AplicarPreButtonPushed(app, event)
            app.filter(app, app.wavfile)
        end

        % Value changed function: Demuestrear
        function DemuestrearValueChanged(app, event)
            if app.Demuestrear.Value
                app.Dsample = downsample(app.filtrada, 2);
                app.Dfs = app.RecordedFs/2;
                app.segmentacion(app, app.Dsample, app.Dfs)
                app.Espectro(app, app.Framed, app.Dfs, app.UIAxes2, 'Señal Demuestreada')
            else
                app.segmentacion(app, app.filtrada, app.RecordedFs)
                app.Espectro(app, app.Framed, app.RecordedFs, app.UIAxes2, 'Señal Filtrada')
            end            
        end

        % Button pushed function: VVentanaButton
        function VVentanaButtonPushed(app, event)
            cla(app.UIAxes2, 'reset');
            x = 0:app.WinLength-1;
            plot(app.UIAxes2, x, app.Win)
            axis(app.UIAxes2, 'tight')
            xlabel(app.UIAxes2, 'Muestras');
            ylabel(app.UIAxes2, 'Amplitud');
            title(app.UIAxes2, 'Ventana seleccionada')   
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
        end

        % Button pushed function: GuardarEspButton
        function GuardarEspButtonPushed(app, event)
            if app.Demuestrar.Value
                app.Framedfs = app.Dfs;
            else
                app.Framedfs = app.RecordedFs;
            end
        end

        % Value changed function: ParametroFB
        function ParametroFBValueChanged(app, event)
            if strcmpi(app.ParametroFB.Value,'Bandwidth')
                app.MelBandwidth.Enable = 'on';
                app.NMelFilter.Enable = 'off';                
                app.NFB = 0;
            else
                app.MelBandwidth.Enable = 'off';
                app.NMelFilter.Enable = 'on';
                app.FBBandwidth = 0;
            end
        end

        % Value changed function: LimiteInferior
        function LimiteInferiorValueChanged(app, event)
            app.filterBankParameters(app)
        end

        % Value changed function: LimiteSuperior
        function LimiteSuperiorValueChanged(app, event)
            app.filterBankParameters(app)
        end

        % Value changed function: NMelFilter
        function NMelFilterValueChanged(app, event)
            app.filterBankParameters(app)            
        end

        % Value changed function: MelBandwidth
        function MelBandwidthValueChanged(app, event)
            app.filterBankParameters(app)
        end

        % Button pushed function: GenFBButton
        function GenFBButtonPushed(app, event)
            app.MFCCButton.Enable = 'on';
            app.Normalizar.Enable = 'on';
            app.MelFilterBank(app)
        end

        % Value changed function: Normalizar
        function NormalizarValueChanged(app, event)
            app.FBNormalization(app)
        end

        % Value changed function: IncluirLogE
        function IncluirLogEValueChanged(app, event)
            app.logE = log(sum(app.Sxx.^2)/app.WinLength);
        end

        % Button pushed function: GenMFCCButton
        function GenMFCCButtonPushed(app, event)
            app.MFCC(app)
        end

        % Value changed function: IncluirDeltas
        function IncluirDeltasValueChanged(app, event)
            app.IncluirDeltas2do.Enable ='on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Reproducir = uibutton(app.UIFigure, 'push');
            app.Reproducir.ButtonPushedFcn = createCallbackFcn(app, @ReproducirPushed, true);
            app.Reproducir.Enable = 'off';
            app.Reproducir.Text = 'Reproducir';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Adquisición de Voz')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Intensidad')
            app.Lamp = uilamp(app.UIFigure);
            app.Guardar = uibutton(app.UIFigure, 'push');
            app.Guardar.ButtonPushedFcn = createCallbackFcn(app, @GuardarPushed, true);
            app.Guardar.Enable = 'off';
            app.Guardar.Text = 'Guardar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AdquisicinTab = uitab(app.TabGroup);
            app.AdquisicinTab.Title = 'Adquisición';
            app.FrecuenciademuestreoDropDownLabel = uilabel(app.AdquisicinTab);
            app.FrecuenciademuestreoDropDownLabel.Text = 'Frecuencia de muestreo';
            app.fMuestreo = uidropdown(app.AdquisicinTab);
            app.fMuestreo.Items = {'8000 Hz', '11025 Hz', '22050 Hz', '44100 Hz'};
            app.fMuestreo.ItemsData = {'8000', ' 11025', ' 22050', ' 44100'};
            app.fMuestreo.Value = ' 22050';
            app.NombreEditFieldLabel = uilabel(app.AdquisicinTab);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.Nombre = uieditfield(app.AdquisicinTab, 'text');
            app.EdadEditFieldLabel = uilabel(app.AdquisicinTab);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.Edad = uieditfield(app.AdquisicinTab, 'numeric');
            app.Edad.Limits = [0 35];
            app.Edad.ValueDisplayFormat = '%.0f';
            app.Edad.Value = 18;
            app.DuracinsegLabel = uilabel(app.AdquisicinTab);
            app.DuracinsegLabel.Text = 'Duración (seg)';
            app.Duracion = uieditfield(app.AdquisicinTab, 'numeric');
            app.Duracion.Limits = [1 5];
            app.Duracion.ValueDisplayFormat = '%.1f';
            app.Duracion.Value = 1.5;
            app.ResolucinDropDownLabel = uilabel(app.AdquisicinTab);
            app.ResolucinDropDownLabel.Text = 'Resolución';
            app.Resolucion = uidropdown(app.AdquisicinTab);
            app.Resolucion.Items = {'8 Bits', '16 Bits'};
            app.Resolucion.ItemsData = {'8', '16'};
            app.Resolucion.Value = '16';
            app.DirSelect = uibutton(app.AdquisicinTab, 'push');
            app.DirSelect.ButtonPushedFcn = createCallbackFcn(app, @DirSelectButtonPushed, true);
            app.DirSelect.Text = 'Seleccionar carpeta';
            app.Ready2Rec = uicheckbox(app.AdquisicinTab);
            app.Ready2Rec.ValueChangedFcn = createCallbackFcn(app, @Ready2RecValueChanged, true);
            app.Ready2Rec.Text = 'Listo';
            app.SexoDropDownLabel = uilabel(app.AdquisicinTab);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.Sexo = uidropdown(app.AdquisicinTab);
            app.Sexo.Items = {'Mujer', 'Hombre'};
            app.Sexo.Value = 'Mujer';
            app.VoluntarioLabel = uilabel(app.AdquisicinTab);
            app.VoluntarioLabel.Text = 'Datos del Voluntario';
            app.EscolaridadDropDownLabel = uilabel(app.AdquisicinTab);
            app.EscolaridadDropDownLabel.Text = 'Escolaridad';
            app.Escolaridad = uidropdown(app.AdquisicinTab);
            app.Escolaridad.Items = {'Preparatoria', 'Licenciatura', 'Posgrado'};
            app.Escolaridad.Value = 'Licenciatura';
            app.AudioLabel = uilabel(app.AdquisicinTab);
            app.AudioLabel.Text = 'Configuración de audio';
            app.Label = uilabel(app.AdquisicinTab);
            app.PatronesTab = uitab(app.TabGroup);
            app.PatronesTab.Title = 'Patrones';
            app.UIAxes2 = uiaxes(app.PatronesTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AjustesTab = uitabgroup(app.PatronesTab);
            app.PreprocesamientoTab = uitab(app.AjustesTab);
            app.PreprocesamientoTab.Title = 'Preprocesamiento';
            app.FrecuenciasdecorteparafiltropasabandasLabel = uilabel(app.PreprocesamientoTab);
            app.FrecuenciasdecorteparafiltropasabandasLabel.Text = 'Frecuencias de corte para filtro pasabandas';
            app.FCorteL = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.FCorteL.Value = 300;
            app.FCorteH = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.FCorteH.Value = 3750;
            app.Bode = uibutton(app.PreprocesamientoTab, 'state');
            app.Bode.ValueChangedFcn = createCallbackFcn(app, @BodeValueChanged, true);
            app.Bode.Text = 'Ver respuesta en frecuencia';
            app.AplicarPre = uibutton(app.PreprocesamientoTab, 'push');
            app.AplicarPre.ButtonPushedFcn = createCallbackFcn(app, @AplicarPreButtonPushed, true);
            app.AplicarPre.Text = 'Aplicar';
            app.TipodeventanaDropDownLabel = uilabel(app.PreprocesamientoTab);
            app.TipodeventanaDropDownLabel.Text = 'Tipo de ventana';
            app.TipoVentana = uidropdown(app.PreprocesamientoTab);
            app.TipoVentana.Items = {'Hamming', 'Hann', 'Kaiser-Bessel', 'Rectangle'};
            app.TipoVentana.ValueChangedFcn = createCallbackFcn(app, @TipoVentanaValueChanged, true);
            app.TipoVentana.Value = 'Hamming';
            app.BetaKaiser = uieditfield(app.PreprocesamientoTab, 'numeric');
            app.BetaKaiser.ValueChangedFcn = createCallbackFcn(app, @BetaKaiserValueChanged, true);
            app.BetaKaiser.Value = 0.5;
            app.BetaKaiserLabel = uilabel(app.PreprocesamientoTab);
            app.BetaKaiserLabel.Text = 'Label2';
            app.VVentanaButton = uibutton(app.PreprocesamientoTab, 'push');
            app.VVentanaButton.ButtonPushedFcn = createCallbackFcn(app, @VVentanaButtonPushed, true);
            app.VVentanaButton.Text = 'Visualizar ventana';
            app.EspectroTab = uitab(app.AjustesTab);
            app.EspectroTab.Title = 'Espectro';
            app.desobreposicinLabel = uilabel(app.EspectroTab);
            app.desobreposicinLabel.Text = '% de sobreposición';
            app.OverKnob = uiknob(app.EspectroTab, 'continuous');
            app.OverKnob.Value = 50;
            app.TamaodelaventanamsLabel = uilabel(app.EspectroTab);
            app.TamaodelaventanamsLabel.Text = 'Tamaño de la ventana (ms)';
            app.TWin = uieditfield(app.EspectroTab, 'numeric');
            app.TWin.Limits = [2 Inf];
            app.TWin.Value = 20;
            app.AplicarEspectro = uibutton(app.EspectroTab, 'push');
            app.AplicarEspectro.ButtonPushedFcn = createCallbackFcn(app, @AplicarEspectroButtonPushed, true);
            app.AplicarEspectro.Text = 'Aplicar';
            app.Demuestrear = uicheckbox(app.EspectroTab);
            app.Demuestrear.ValueChangedFcn = createCallbackFcn(app, @DemuestrearValueChanged, true);
            app.Demuestrear.Text = 'Demuestrear';
            app.GuardarEspButton = uibutton(app.EspectroTab, 'push');
            app.GuardarEspButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarEspButtonPushed, true);
            app.GuardarEspButton.Text = 'Guardar';
            app.MelTab = uitab(app.AjustesTab);
            app.MelTab.Title = 'Mel';
            app.NmerofiltrosSpinnerLabel = uilabel(app.MelTab);
            app.NmerofiltrosSpinnerLabel.Text = 'Número filtros';
            app.NMelFilter = uispinner(app.MelTab);
            app.NMelFilter.ValueChangedFcn = createCallbackFcn(app, @NMelFilterValueChanged, true);
            app.NMelFilter.Value = 35;
            app.LimiteinferiorEditFieldLabel = uilabel(app.MelTab);
            app.LimiteinferiorEditFieldLabel.Text = 'Limite inferior';
            app.LimiteInferior = uieditfield(app.MelTab, 'numeric');
            app.LimiteInferior.Limits = [0 Inf];
            app.LimiteInferior.ValueChangedFcn = createCallbackFcn(app, @LimiteInferiorValueChanged, true);
            app.LimitesuperiorEditFieldLabel = uilabel(app.MelTab);
            app.LimitesuperiorEditFieldLabel.Text = 'Limite superior';
            app.LimiteSuperior = uieditfield(app.MelTab, 'numeric');
            app.LimiteSuperior.Limits = [0 Inf];
            app.LimiteSuperior.ValueChangedFcn = createCallbackFcn(app, @LimiteSuperiorValueChanged, true);
            app.AnchodebandamelsSpinnerLabel = uilabel(app.MelTab);
            app.AnchodebandamelsSpinnerLabel.Enable = 'off';
            app.AnchodebandamelsSpinnerLabel.Text = 'Ancho de banda (mels)';
            app.MelBandwidth = uispinner(app.MelTab);
            app.MelBandwidth.ValueChangedFcn = createCallbackFcn(app, @MelBandwidthValueChanged, true);
            app.MelBandwidth.Enable = 'off';
            app.MelBandwidth.Value = 110;
            app.ParametroFB = uiswitch(app.MelTab, 'slider');
            app.ParametroFB.Items = {'nBands', 'Bandwidth'};
            app.ParametroFB.ValueChangedFcn = createCallbackFcn(app, @ParametroFBValueChanged, true);
            app.ParametroFB.Value = 'nBands';
            app.GenFBButton = uibutton(app.MelTab, 'push');
            app.GenFBButton.ButtonPushedFcn = createCallbackFcn(app, @GenFBButtonPushed, true);
            app.GenFBButton.Text = 'Generar banco de Filtros';
            app.NormalizarporDropDownLabel = uilabel(app.MelTab);
            app.NormalizarporDropDownLabel.Enable = 'off';
            app.NormalizarporDropDownLabel.Text = 'Normalizar por:';
            app.Normalizar = uidropdown(app.MelTab);
            app.Normalizar.Items = {'Area', 'Ancho de Banda', 'Seleccionar'};
            app.Normalizar.ValueChangedFcn = createCallbackFcn(app, @NormalizarValueChanged, true);
            app.Normalizar.Enable = 'off';
            app.Normalizar.Value = 'Seleccionar';
            app.RangodelbancodefiltrosMelLabel = uilabel(app.MelTab);
            app.RangodelbancodefiltrosMelLabel.Text = 'Rango del banco de filtros Mel';
            app.MFCCTab = uitab(app.AjustesTab);
            app.MFCCTab.Title = 'MFCC';
            app.CoeficientesMFCCEditFieldLabel = uilabel(app.MFCCTab);
            app.CoeficientesMFCCEditFieldLabel.Text = '# Coeficientes MFCC';
            app.nMFCC = uieditfield(app.MFCCTab, 'numeric');
            app.nMFCC.Limits = [0 Inf];
            app.nMFCC.Value = 13;
            app.IncluirLogE = uicheckbox(app.MFCCTab);
            app.IncluirLogE.ValueChangedFcn = createCallbackFcn(app, @IncluirLogEValueChanged, true);
            app.IncluirLogE.Text = 'Incluir Log E';
            app.IncluirDeltas2do = uicheckbox(app.MFCCTab);
            app.IncluirDeltas2do.Text = 'Incluir Deltas de 2° orden';
            app.GenMFCCButton = uibutton(app.MFCCTab, 'push');
            app.GenMFCCButton.ButtonPushedFcn = createCallbackFcn(app, @GenMFCCButtonPushed, true);
            app.GenMFCCButton.Text = 'Generar Coeficientes';
            app.IncluirDeltas = uicheckbox(app.MFCCTab);
            app.IncluirDeltas.ValueChangedFcn = createCallbackFcn(app, @IncluirDeltasValueChanged, true);
            app.IncluirDeltas.Text = 'Incluir Deltas';
            app.EspectroButton = uibutton(app.PatronesTab, 'push');
            app.EspectroButton.ButtonPushedFcn = createCallbackFcn(app, @EspectroButtonPushed, true);
            app.EspectroButton.Enable = 'off';
            app.EspectroButton.Text = 'Espectro';
            app.FiltroButton = uibutton(app.PatronesTab, 'push');
            app.FiltroButton.ButtonPushedFcn = createCallbackFcn(app, @FiltroButtonPushed, true);
            app.FiltroButton.Enable = 'off';
            app.FiltroButton.Text = 'Filtro';
            app.MelButton = uibutton(app.PatronesTab, 'push');
            app.MelButton.ButtonPushedFcn = createCallbackFcn(app, @MelButtonPushed, true);
            app.MelButton.Enable = 'off';
            app.MelButton.Text = 'Filtro Mel';
            app.MFCCButton = uibutton(app.PatronesTab, 'push');
            app.MFCCButton.ButtonPushedFcn = createCallbackFcn(app, @MFCCButtonPushed, true);
            app.MFCCButton.Enable = 'off';
            app.MFCCButton.Text = 'MFCC';
            app.ProcesarButton = uibutton(app.PatronesTab, 'push');
            app.ProcesarButton.Enable = 'off';
            app.ProcesarButton.Text = 'Procesar base';
            app.Mensaje = uilabel(app.UIFigure);
            app.Mensaje.Text = '';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = 'Apagado';
            app.Palabra = uilabel(app.UIFigure);
            app.Palabra.Text = '';
            app.ApagarSwitchLabel = uilabel(app.UIFigure);
            app.ApagarSwitchLabel.Text = 'Apagar';
            app.Apagar = uiswitch(app.UIFigure, 'toggle');
            app.Apagar.ValueChangedFcn = createCallbackFcn(app, @ApagarValueChanged, true);
            app.Apagar.Value = 'On';
            app.GrabarLabel = uilabel(app.UIFigure);
            app.GrabarLabel.Text = 'Grabar';
            app.Grabar = uiswitch(app.UIFigure, 'slider');
            app.Grabar.ValueChangedFcn = createCallbackFcn(app, @GrabarValueChanged, true);
            app.Grabar.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = adquisicion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end